classdef DSCmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSCmapUIFigure                matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        TabGroup4                     matlab.ui.container.TabGroup
        ArterialInputFunctionTab      matlab.ui.container.Tab
        YViewField                    matlab.ui.control.NumericEditField
        YViewFieldLabel               matlab.ui.control.Label
        XViewField                    matlab.ui.control.NumericEditField
        XViewFieldLabel               matlab.ui.control.Label
        ConcInputFunctionPlot         matlab.ui.control.UIAxes
        ArterialInputFunctionPlot     matlab.ui.control.UIAxes
        FitParametersTab              matlab.ui.container.Tab
        CBFfitMethodButtonGroup       matlab.ui.container.ButtonGroup
        oSVDLabel                     matlab.ui.control.Label
        cSVDLabel                     matlab.ui.control.Label
        SVDLabel                      matlab.ui.control.Label
        RhoEditField                  matlab.ui.control.NumericEditField
        RhoEditFieldLabel             matlab.ui.control.Label
        KhEditField                   matlab.ui.control.NumericEditField
        KhEditFieldLabel              matlab.ui.control.Label
        oSVDButton                    matlab.ui.control.RadioButton
        cSVDButton                    matlab.ui.control.RadioButton
        SVDButton                     matlab.ui.control.RadioButton
        AIFSettingsPanel              matlab.ui.container.Panel
        QuadraticCheckBoxLabel        matlab.ui.control.Label
        QuadraticCheckBox             matlab.ui.control.CheckBox
        MaxAIFpixelsEditField         matlab.ui.control.NumericEditField
        MaxAIFpixelsEditFieldLabel    matlab.ui.control.Label
        ShortAxisEditField            matlab.ui.control.NumericEditField
        ShortAxisEditFieldLabel       matlab.ui.control.Label
        LongAxisEditField             matlab.ui.control.NumericEditField
        LongAxisEditFieldLabel        matlab.ui.control.Label
        DataSelectionPanel            matlab.ui.container.Panel
        FirstDynamicEditField         matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel    matlab.ui.control.Label
        FitEvaluationTab              matlab.ui.container.Tab
        ConcentrationPlot             matlab.ui.control.UIAxes
        ResidualPlot                  matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        RawImage                      matlab.ui.control.Image
        ImageWindowLevelPanel         matlab.ui.container.Panel
        AutoLWButton                  matlab.ui.control.StateButton
        WindowEditFieldLabel          matlab.ui.control.Label
        LevelEditFieldLabel           matlab.ui.control.Label
        LevelSlider                   matlab.ui.control.Slider
        LevelEditField                matlab.ui.control.NumericEditField
        WindowSlider                  matlab.ui.control.Slider
        WindowEditField               matlab.ui.control.NumericEditField
        MaskSettingsPanel             matlab.ui.container.Panel
        thresholdEditField            matlab.ui.control.NumericEditField
        thresholdEditFieldLabel       matlab.ui.control.Label
        OpenButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        ErodeButton                   matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        ImageSelectionPanel           matlab.ui.container.Panel
        DynamicsEditFieldLabel        matlab.ui.control.Label
        SlicesEditFieldLabel          matlab.ui.control.Label
        DynamicsSlider                matlab.ui.control.Slider
        DynamicsEditField             matlab.ui.control.NumericEditField
        SlicesSlider                  matlab.ui.control.Slider
        SlicesEditField               matlab.ui.control.NumericEditField
        ImageLabel                    matlab.ui.control.Label
        RawDataFig                    matlab.ui.control.UIAxes
        SequenceParametersTab         matlab.ui.container.Tab
        SliceThicknessViewField       matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel  matlab.ui.control.Label
        SlicesViewField               matlab.ui.control.NumericEditField
        SlicesViewFieldLabel          matlab.ui.control.Label
        dimyViewField                 matlab.ui.control.NumericEditField
        dimyViewFieldLabel            matlab.ui.control.Label
        dimxViewField                 matlab.ui.control.NumericEditField
        dimxViewFieldLabel            matlab.ui.control.Label
        FOVViewField                  matlab.ui.control.NumericEditField
        FOVViewFieldLabel             matlab.ui.control.Label
        AspectRatioViewField          matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel     matlab.ui.control.Label
        NRDynamicsViewField           matlab.ui.control.NumericEditField
        NRDynamicsViewFieldLabel      matlab.ui.control.Label
        FlipAngleViewField            matlab.ui.control.NumericEditField
        FlipAngleViewFieldLabel       matlab.ui.control.Label
        TEViewField                   matlab.ui.control.NumericEditField
        TEViewFieldLabel              matlab.ui.control.Label
        TRViewField                   matlab.ui.control.NumericEditField
        TRViewFieldLabel              matlab.ui.control.Label
        DicomFolderViewField          matlab.ui.control.EditField
        DicomFolderViewFieldLabel     matlab.ui.control.Label
        AppSettingsTab                matlab.ui.container.Tab
        ColorsButtonGroup             matlab.ui.container.ButtonGroup
        BlackWhiteButton              matlab.ui.control.RadioButton
        ClassicButton                 matlab.ui.control.RadioButton
        ColorsLabel                   matlab.ui.control.Label
        AutoSoundsCheckBoxLabel       matlab.ui.control.Label
        AutoSoundsCheckBox            matlab.ui.control.CheckBox
        SoundsLabel                   matlab.ui.control.Label
        TabGroup3                     matlab.ui.container.TabGroup
        rCBVrCBFTab                   matlab.ui.container.Tab
        CBFColorMapButtonGroup        matlab.ui.container.ButtonGroup
        CBFScaleAutoButton            matlab.ui.control.Button
        CBFScaleMaxLabel              matlab.ui.control.Label
        CBFScaleEditField             matlab.ui.control.NumericEditField
        CBFnaviaButton                matlab.ui.control.RadioButton
        CBFparulaButton               matlab.ui.control.RadioButton
        CBFwinterButton               matlab.ui.control.RadioButton
        CBFlipariButton               matlab.ui.control.RadioButton
        CBFgrayButton                 matlab.ui.control.RadioButton
        CBVColorMapButtonGroup        matlab.ui.container.ButtonGroup
        CBVScaleAutoButton            matlab.ui.control.Button
        CBVScaleMaxLabel              matlab.ui.control.Label
        CBVScaleEditField             matlab.ui.control.NumericEditField
        CBVnaviaButton                matlab.ui.control.RadioButton
        CBVparulaButton               matlab.ui.control.RadioButton
        CBVwinterButton               matlab.ui.control.RadioButton
        CBVlipariButton               matlab.ui.control.RadioButton
        CBVgrayButton                 matlab.ui.control.RadioButton
        MapImageSelectionPanel1       matlab.ui.container.Panel
        MapSlicesEditField1           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel1      matlab.ui.control.Label
        MapSlicesSlider1              matlab.ui.control.Slider
        CBVImage                      matlab.ui.control.Image
        rCBF1sLabel                   matlab.ui.control.Label
        rCBVLabel                     matlab.ui.control.Label
        CBFImage                      matlab.ui.control.Image
        CBFMapFig                     matlab.ui.control.UIAxes
        CBVMapFig                     matlab.ui.control.UIAxes
        MTTTTPTab                     matlab.ui.container.Tab
        TTPColorMapButtonGroup        matlab.ui.container.ButtonGroup
        TTPScaleAutoButton            matlab.ui.control.Button
        TTPScaleMaxLabel              matlab.ui.control.Label
        TTPScaleEditField             matlab.ui.control.NumericEditField
        TTPnaviaButton                matlab.ui.control.RadioButton
        TTPparulaButton               matlab.ui.control.RadioButton
        TTPwinterButton               matlab.ui.control.RadioButton
        TTPlipariButton               matlab.ui.control.RadioButton
        TTPgrayButton                 matlab.ui.control.RadioButton
        MTTColorMapButtonGroup        matlab.ui.container.ButtonGroup
        MTTScaleAutoButton            matlab.ui.control.Button
        MTTScaleMaxLabel              matlab.ui.control.Label
        MTTScaleEditField             matlab.ui.control.NumericEditField
        MTTnaviaButton                matlab.ui.control.RadioButton
        MTTparulaButton               matlab.ui.control.RadioButton
        MTTwinterButton               matlab.ui.control.RadioButton
        MTTlipariButton               matlab.ui.control.RadioButton
        MTTgrayButton                 matlab.ui.control.RadioButton
        TTPImage                      matlab.ui.control.Image
        MTTImage                      matlab.ui.control.Image
        MapImageSelectionPanel2       matlab.ui.container.Panel
        MapSlicesEditField2           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel2      matlab.ui.control.Label
        MapSlicesSlider2              matlab.ui.control.Slider
        TTPsLabel                     matlab.ui.control.Label
        MTTsLabel                     matlab.ui.control.Label
        TTPMapFig                     matlab.ui.control.UIAxes
        MTTMapFig                     matlab.ui.control.UIAxes
        rCBVlcK2Tab                   matlab.ui.container.Tab
        K2ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        K2ScaleAutoButton             matlab.ui.control.Button
        K2ScaleMaxLabel               matlab.ui.control.Label
        K2ScaleEditField              matlab.ui.control.NumericEditField
        K2naviaButton                 matlab.ui.control.RadioButton
        K2parulaButton                matlab.ui.control.RadioButton
        K2winterButton                matlab.ui.control.RadioButton
        K2lipariButton                matlab.ui.control.RadioButton
        K2grayButton                  matlab.ui.control.RadioButton
        CBVlcColorMapButtonGroup      matlab.ui.container.ButtonGroup
        CBVlcScaleAutoButton          matlab.ui.control.Button
        CBVlcScaleMaxLabel            matlab.ui.control.Label
        CBVlcScaleEditField           matlab.ui.control.NumericEditField
        CBVlcNaviaButton              matlab.ui.control.RadioButton
        CBVlcparulaButton             matlab.ui.control.RadioButton
        CBVlcwinterButton             matlab.ui.control.RadioButton
        CBVllipariButton              matlab.ui.control.RadioButton
        CBVlcgrayButton               matlab.ui.control.RadioButton
        MapImageSelectionPanel3       matlab.ui.container.Panel
        MapSlicesEditField3           matlab.ui.control.NumericEditField
        MapSlicesEditFieldLabel3      matlab.ui.control.Label
        MapSlicesSlider3              matlab.ui.control.Slider
        CBVlcImage                    matlab.ui.control.Image
        K2Label                       matlab.ui.control.Label
        rCBVlcLabel                   matlab.ui.control.Label
        K2Image                       matlab.ui.control.Image
        K2MapFig                      matlab.ui.control.UIAxes
        CBVlcMapFig                   matlab.ui.control.UIAxes
        TabGroup1                     matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        EstimatedFitTimeViewField     matlab.ui.control.EditField
        EstimatedRegTimeViewField     matlab.ui.control.EditField
        AbortRegButton                matlab.ui.control.Button
        RegistrationDropDown          matlab.ui.control.DropDown
        RegProgressLabel              matlab.ui.control.Label
        RegProgressGauge              matlab.ui.control.LinearGauge
        Lamp2                         matlab.ui.control.Lamp
        RegisterButton                matlab.ui.control.Button
        ExportGIFButton               matlab.ui.control.Button
        Lamp6                         matlab.ui.control.Lamp
        FitProgressLabel              matlab.ui.control.Label
        ExportDCMButton               matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        Lamp5                         matlab.ui.control.Lamp
        FitProgressGauge              matlab.ui.control.LinearGauge
        Lamp4                         matlab.ui.control.Lamp
        FitDSCButton                  matlab.ui.control.Button
        Lamp3                         matlab.ui.control.Lamp
        MaskButton                    matlab.ui.control.Button
        Lamp1                         matlab.ui.control.Lamp
        LoadDCMfileButton             matlab.ui.control.Button
        MRSImage                      matlab.ui.control.Image
        StatusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        TulpLogo                      matlab.ui.control.Image
        Credits                       matlab.ui.control.Label
        DSCmappingLabel               matlab.ui.control.Label
    end


    %% --------------------------------------------------------------------------------------
    %
    %   DSC mapping tool
    %    
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   July 2025
    %
    %
    %   
    %
    %
    %   Version 1.0
    %   - intial version
    %
    %   Version 1.1
    %   - improved version control
    %
    %   Version 1.2
    %   - Some bug fixes
    %   - Version Matlab 2025a
    %   - new color maps
    %
    %% --------------------------------------------------------------------------------------
    
    %#ok<*ST2NM> 
    
    
    properties (Access = public)

        % Version control
        appVersion = "1.2.3"        % App version
        
        % DSC class
        d                   dsc     % Data and meta data 
       
        % File variables
        appFolder                   % Application folder
        oldFolder                   % Starting folder
        dicomImportFolder           % Dicom import folder
        dicomExportFolder           % Dicom export folder
        gifExportFolder             % gif export folder
         
        % Flags
        validRegFlag                % successful registration true/false
        abortRegFlag                % abort registration false or true
      
        % Images
        images                      % reconstructed raw images (ne, ns, dimx, dimy)
        threshold                   % segmentation threshold (ns,1)
        scalefactor                 % image intensity scale factor
        cross                       % cross-hair
        roiPoints                   % roi point coordinates
        roi                         % roi drawing object in T2 map
        aifroi                      % aif roi
        roiM0                       % roi drawing object in M0 image
        structuringElement2D        % 2D structuring element
        cbvcmap                     % rCBV color mapping
        cbfcmap                     % rCBV color mapping
        mttcmap                     % MTT color mapping
        ttpcmap                     % TTP color mapping
        cbvlccmap                   % rCBVlc color mapping
        k2cmap                      % k2 color mapping
     
        % Fitting
        startParallel = false       % Parallel execution true or false
        totalNrSteps                % Total number of steps for fitting, for progress bar
        counter                     % Progress counter
        elapsedTime                 % Progress bar elapsed time
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        lightblue                   % lightblue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end
    
    
    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX>
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadDCMfileButton.Enable = 'off'; end
            if code(1) == 1 app.LoadDCMfileButton.Enable = 'on';  end
            if code(2) == 0 app.RegisterButton.Enable = 'off'; app.RegistrationDropDown.Enable = 'off'; end
            if code(2) == 1 app.RegisterButton.Enable = 'on';  app.RegistrationDropDown.Enable = 'on';  end
            if code(3) == 0 app.MaskButton.Enable = 'off'; end
            if code(3) == 1 app.MaskButton.Enable = 'on';  end
            if code(4) == 0 app.FitDSCButton.Enable = 'off'; end
            if code(4) == 1 app.FitDSCButton.Enable = 'on';  end
            if code(5) == 0 app.ExportDCMButton.Enable = 'off'; end
            if code(5) == 1 app.ExportDCMButton.Enable = 'on';  end
            if code(6) == 0 app.ExportGIFButton.Enable = 'off'; end
            if code(6) == 1 app.ExportGIFButton.Enable = 'on'; end
            if code(7) == 0 app.ExitButton.Enable = 'off'; end
            if code(7) == 1 app.ExitButton.Enable = 'on';  end
        
            drawnow;
            
        end

         % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            % boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            % gridColor = guiColors{8}; 
         
            % Panels
            app.DSCmapUIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor; 
            app.RawImagesTab.BackgroundColor = backgroundColor; 
            app.SequenceParametersTab.BackgroundColor = backgroundColor;
            app.FitParametersTab.BackgroundColor = backgroundColor; 
            app.FitEvaluationTab.BackgroundColor = backgroundColor; 
            app.AppSettingsTab.BackgroundColor = backgroundColor; 
            app.ArterialInputFunctionTab.BackgroundColor = backgroundColor; 
            app.rCBVrCBFTab.BackgroundColor = backgroundColor; 
            app.MTTTTPTab.BackgroundColor = backgroundColor; 
            app.rCBVlcK2Tab.BackgroundColor = backgroundColor; 
            app.ImageSelectionPanel.BackgroundColor = backgroundColor; 
            app.MapImageSelectionPanel1.BackgroundColor = backgroundColor; 
            app.MapImageSelectionPanel2.BackgroundColor = backgroundColor; 
            app.MapImageSelectionPanel3.BackgroundColor = backgroundColor; 
            app.MaskSettingsPanel.BackgroundColor = backgroundColor; 
            app.ImageWindowLevelPanel.BackgroundColor = backgroundColor; 
            app.DataSelectionPanel.BackgroundColor = backgroundColor; 
            app.AIFSettingsPanel.BackgroundColor = backgroundColor; 
            app.CBFfitMethodButtonGroup.BackgroundColor = backgroundColor; 
            app.ColorsButtonGroup.BackgroundColor = backgroundColor; 
            app.CBVColorMapButtonGroup.BackgroundColor = backgroundColor; 
            app.CBFColorMapButtonGroup.BackgroundColor = backgroundColor; 
            app.MTTColorMapButtonGroup.BackgroundColor = backgroundColor; 
            app.TTPColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.CBVlcColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.K2ColorMapButtonGroup.BackgroundColor = backgroundColor;

            % Fonts
            app.SlicesEditFieldLabel.FontColor = fontColor; 
            app.DynamicsEditFieldLabel.FontColor = fontColor; 
            app.thresholdEditFieldLabel.FontColor = fontColor; 
            app.WindowEditFieldLabel.FontColor = fontColor; 
            app.LevelEditFieldLabel.FontColor = fontColor; 
            app.FirstDynamicEditFieldLabel.FontColor = fontColor; 
            app.MaxAIFpixelsEditFieldLabel.FontColor = fontColor; 
            app.LongAxisEditFieldLabel.FontColor = fontColor; 
            app.ShortAxisEditFieldLabel.FontColor = fontColor; 
            app.QuadraticCheckBoxLabel.FontColor = fontColor;
            app.SVDButton.FontColor = fontColor; 
            app.cSVDButton.FontColor = fontColor; 
            app.oSVDButton.FontColor = fontColor; 
            app.SVDLabel.FontColor = fontColor; 
            app.cSVDLabel.FontColor = fontColor; 
            app.oSVDLabel.FontColor = fontColor; 
            app.KhEditFieldLabel.FontColor = fontColor; 
            app.RhoEditFieldLabel.FontColor = fontColor; 
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor; 
            app.BlackWhiteButton.FontColor = fontColor; 
            app.ClassicButton.FontColor = fontColor; 
            app.DicomFolderViewFieldLabel.FontColor = fontColor; 
            app.TRViewFieldLabel.FontColor = fontColor; 
            app.TEViewFieldLabel.FontColor = fontColor; 
            app.FlipAngleViewFieldLabel.FontColor = fontColor; 
            app.NRDynamicsViewFieldLabel.FontColor = fontColor; 
            app.AspectRatioViewFieldLabel.FontColor = fontColor; 
            app.FOVViewFieldLabel.FontColor = fontColor; 
            app.dimxViewFieldLabel.FontColor = fontColor; 
            app.dimyViewFieldLabel.FontColor = fontColor; 
            app.SlicesViewFieldLabel.FontColor = fontColor; 
            app.SliceThicknessViewFieldLabel.FontColor = fontColor; 
            
            app.XViewFieldLabel.FontColor = fontColor; 
            app.YViewFieldLabel.FontColor = fontColor; 
            
            app.MapSlicesEditFieldLabel1.FontColor = fontColor; 
            app.MapSlicesEditFieldLabel2.FontColor = fontColor; 
            app.MapSlicesEditFieldLabel3.FontColor = fontColor; 

            app.CBVScaleMaxLabel.FontColor = fontColor; 
            app.CBVgrayButton.FontColor = fontColor; 
            app.CBVlipariButton.FontColor = fontColor; 
            app.CBVwinterButton.FontColor = fontColor; 
            app.CBVparulaButton.FontColor = fontColor; 
            app.CBVnaviaButton.FontColor = fontColor; 

            app.CBFScaleMaxLabel.FontColor = fontColor; 
            app.CBFgrayButton.FontColor = fontColor; 
            app.CBFlipariButton.FontColor = fontColor; 
            app.CBFwinterButton.FontColor = fontColor; 
            app.CBFparulaButton.FontColor = fontColor; 
            app.CBFnaviaButton.FontColor = fontColor; 
            
            app.MTTScaleMaxLabel.FontColor = fontColor; 
            app.MTTgrayButton.FontColor = fontColor; 
            app.MTTlipariButton.FontColor = fontColor; 
            app.MTTwinterButton.FontColor = fontColor; 
            app.MTTparulaButton.FontColor = fontColor; 
            app.MTTnaviaButton.FontColor = fontColor; 

            app.TTPScaleMaxLabel.FontColor = fontColor; 
            app.TTPgrayButton.FontColor = fontColor; 
            app.TTPlipariButton.FontColor = fontColor; 
            app.TTPwinterButton.FontColor = fontColor; 
            app.TTPparulaButton.FontColor = fontColor; 
            app.TTPnaviaButton.FontColor = fontColor; 

            app.CBVlcScaleMaxLabel.FontColor = fontColor;
            app.CBVlcgrayButton.FontColor = fontColor; 
            app.CBVllipariButton.FontColor = fontColor; 
            app.CBVlcwinterButton.FontColor = fontColor; 
            app.CBVlcparulaButton.FontColor = fontColor; 
            app.CBVlcNaviaButton.FontColor = fontColor; 

            app.K2ScaleMaxLabel.FontColor = fontColor; 
            app.K2grayButton.FontColor = fontColor; 
            app.K2lipariButton.FontColor = fontColor; 
            app.K2winterButton.FontColor = fontColor; 
            app.K2parulaButton.FontColor = fontColor; 
            app.K2naviaButton.FontColor = fontColor; 
    
            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images
            app.ImageLabel.FontColor = fontColor;
            app.rCBVLabel.FontColor = fontColor;
            app.rCBF1sLabel.FontColor = fontColor;
            app.MTTsLabel.FontColor = fontColor;
            app.TTPsLabel.FontColor = fontColor;
            app.rCBVlcLabel.FontColor = fontColor;
            app.K2Label.FontColor = fontColor;

            app.RawDataFig.Color = movieFigureColor;
            app.RawDataFig.XColor = movieFigureColor;
            app.RawDataFig.YColor = movieFigureColor;

            app.CBVMapFig.Color = movieFigureColor;
            app.CBVMapFig.XColor = movieFigureColor;
            app.CBVMapFig.YColor = movieFigureColor;

            app.CBFMapFig.Color = movieFigureColor;
            app.CBFMapFig.XColor = movieFigureColor;
            app.CBFMapFig.YColor = movieFigureColor;

            app.MTTMapFig.Color = movieFigureColor;
            app.MTTMapFig.XColor = movieFigureColor;
            app.MTTMapFig.YColor = movieFigureColor;

            app.TTPMapFig.Color = movieFigureColor;
            app.TTPMapFig.XColor = movieFigureColor;
            app.TTPMapFig.YColor = movieFigureColor;

            app.CBVlcMapFig.Color = movieFigureColor;
            app.CBVlcMapFig.XColor = movieFigureColor;
            app.CBVlcMapFig.YColor = movieFigureColor;

            app.K2MapFig.Color = movieFigureColor;
            app.K2MapFig.XColor = movieFigureColor;
            app.K2MapFig.YColor = movieFigureColor;

            app.ArterialInputFunctionPlot.Color = figureColor;
            app.ArterialInputFunctionPlot.XColor = fontColor;
            app.ArterialInputFunctionPlot.YColor = fontColor;

            app.ConcInputFunctionPlot.Color = figureColor;
            app.ConcInputFunctionPlot.XColor = fontColor;
            app.ConcInputFunctionPlot.YColor = fontColor;

            app.ResidualPlot.Color = figureColor;
            app.ResidualPlot.XColor = fontColor;
            app.ResidualPlot.YColor = fontColor;

            app.ConcentrationPlot.Color = figureColor;
            app.ConcentrationPlot.XColor = fontColor;
            app.ConcentrationPlot.YColor = fontColor;

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);

                newestVersions = {"1.9","1.8","1.7","1.5","1.4","1.3","1.2","1.1","1.0"};

                webtxt = [];
                ver = 0;
                while isempty(webtxt) && ver<=length(newestVersions)
                    try
                        ver = ver + 1;
                        newVersionFile = strcat("https://github.com/Moby1971/DSCmapping-v",newestVersions{ver},"/blob/main/appversion.txt");
                        webtxt = webread(newVersionFile);
                    catch
                    end
                end
              
                pos = strfind(lower(webtxt),lower("DSCmapp_version="))+16;
                newVersion = webtxt(pos:pos+strlength(newestVersions{ver})+1);

                app.TextMessage(strcat("Current version = ",currentVersion));
                app.TextMessage(strcat("Newest version = ",newVersion));
               
                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion); 
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);

                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of the DSC mapping app is available ...');
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...');
                else
                    app.TextMessage('The DSC mapping app is up to date ...');
                end

            catch
            end

        end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstDynamicEditField.Enable = 'off';
                app.FirstDynamicEditFieldLabel.Enable = 'off';
                app.MaxAIFpixelsEditField.Enable = 'off';
                app.MaxAIFpixelsEditFieldLabel.Enable = 'off';
                app.LongAxisEditField.Enable = 'off';
                app.LongAxisEditFieldLabel.Enable = 'off';
                app.ShortAxisEditField.Enable = 'off';
                app.ShortAxisEditFieldLabel.Enable = 'off';
                app.QuadraticCheckBox.Enable = 'off';
                app.QuadraticCheckBoxLabel.Enable = 'off';
                app.SVDButton.Enable = 'off';
                app.cSVDButton.Enable = 'off';
                app.oSVDButton.Enable = 'off';
                app.SVDLabel.Enable = 'off';
                app.cSVDLabel.Enable = 'off';
                app.oSVDLabel.Enable = 'off';
                app.KhEditField.Enable = 'off';
                app.KhEditFieldLabel.Enable = 'off';
                app.RhoEditField.Enable = 'off';
                app.RhoEditFieldLabel.Enable = 'off';
             
            else
            
                app.FirstDynamicEditField.Enable = 'on';
                app.FirstDynamicEditFieldLabel.Enable = 'on';
                app.MaxAIFpixelsEditField.Enable = 'on';
                app.MaxAIFpixelsEditFieldLabel.Enable = 'on';
                app.LongAxisEditField.Enable = 'on';
                app.LongAxisEditFieldLabel.Enable = 'on';
                app.ShortAxisEditField.Enable = 'on';
                app.ShortAxisEditFieldLabel.Enable = 'on';
                app.QuadraticCheckBox.Enable = 'on';
                app.QuadraticCheckBoxLabel.Enable = 'on';
                app.SVDButton.Enable = 'on';
                app.cSVDButton.Enable = 'on';
                app.oSVDButton.Enable = 'on';
                app.SVDLabel.Enable = 'on';
                app.cSVDLabel.Enable = 'on';
                app.oSVDLabel.Enable = 'on';
                app.KhEditField.Enable = 'on';
                app.KhEditFieldLabel.Enable = 'on';
                app.RhoEditField.Enable = 'on';
                app.RhoEditFieldLabel.Enable = 'on';
                
            end
                                   
            drawnow;
            
        end % SetFitButtons
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0

                app.SlicesEditField.Enable = 'off';
                app.SlicesEditFieldLabel.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.DynamicsEditFieldLabel.Enable = 'off';
                app.DynamicsSlider.Enable = 'off';
                app.WindowEditField.Enable = 'off';
                app.WindowEditFieldLabel.Enable = 'off';
                app.WindowSlider.Enable = 'off';
                app.LevelEditField.Enable = 'off';
                app.LevelEditFieldLabel.Enable = 'off';
                app.LevelSlider.Enable = 'off';
                app.AutoLWButton.Enable = 'off';
                app.FirstDynamicEditField.Enable = 'off';
                app.FirstDynamicEditFieldLabel.Enable = 'off';
                app.MaxAIFpixelsEditField.Enable = 'off';
                app.MaxAIFpixelsEditFieldLabel.Enable = 'off';
                app.LongAxisEditField.Enable = 'off';
                app.LongAxisEditFieldLabel.Enable = 'off';
                app.ShortAxisEditField.Enable = 'off';
                app.ShortAxisEditFieldLabel.Enable = 'off';
                app.QuadraticCheckBox.Enable = 'off';
                app.QuadraticCheckBoxLabel.Enable = 'off';
                app.SVDButton.Enable = 'off';
                app.cSVDButton.Enable = 'off';
                app.oSVDButton.Enable = 'off';
                app.SVDLabel.Enable = 'off';
                app.cSVDLabel.Enable = 'off';
                app.oSVDLabel.Enable = 'off';
                app.KhEditField.Enable = 'off';
                app.KhEditFieldLabel.Enable = 'off';
                app.RhoEditField.Enable = 'off';
                app.RhoEditFieldLabel.Enable = 'off';

            else

                app.SlicesEditField.Enable = 'on';
                app.SlicesEditFieldLabel.Enable = 'on';
                app.SlicesSlider.Enable = 'on';
                app.DynamicsEditField.Enable = 'on';
                app.DynamicsEditFieldLabel.Enable = 'on';
                app.DynamicsSlider.Enable = 'on';
                app.WindowEditField.Enable = 'on';
                app.WindowEditFieldLabel.Enable = 'on';
                app.WindowSlider.Enable = 'on';
                app.LevelEditField.Enable = 'on';
                app.LevelEditFieldLabel.Enable = 'on';
                app.LevelSlider.Enable = 'on';
                app.AutoLWButton.Enable = 'on';
                app.FirstDynamicEditField.Enable = 'on';
                app.FirstDynamicEditFieldLabel.Enable = 'on';
                app.MaxAIFpixelsEditField.Enable = 'on';
                app.MaxAIFpixelsEditFieldLabel.Enable = 'on';
                app.LongAxisEditField.Enable = 'on';
                app.LongAxisEditFieldLabel.Enable = 'on';
                app.ShortAxisEditField.Enable = 'on';
                app.ShortAxisEditFieldLabel.Enable = 'on';
                app.QuadraticCheckBox.Enable = 'on';
                app.QuadraticCheckBoxLabel.Enable = 'on';
                app.SVDButton.Enable = 'on';
                app.cSVDButton.Enable = 'on';
                app.oSVDButton.Enable = 'on';
                app.SVDLabel.Enable = 'on';
                app.cSVDLabel.Enable = 'on';
                app.oSVDLabel.Enable = 'on';
                app.KhEditField.Enable = 'on';
                app.KhEditFieldLabel.Enable = 'on';
                app.RhoEditField.Enable = 'on';
                app.RhoEditFieldLabel.Enable = 'on';

            end
            
            drawnow;
            
        end % SetImageSelectionButtons
        
                
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0

                app.thresholdEditField.Enable = 'off';
                app.thresholdEditFieldLabel.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';

            else

                app.thresholdEditField.Enable = 'on';
                app.thresholdEditFieldLabel.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';

            end
            
            drawnow;
            
        end % SetSegmentationButtons


        % Switch map buttons on/off
        function SetMapsButtons(app,code)

            if code == 0

                app.MapSlicesEditField1.Enable = 'off';
                app.MapSlicesEditFieldLabel1.Enable = 'off';
                app.MapSlicesSlider1.Enable = 'off';
                app.MapSlicesEditField2.Enable = 'off';
                app.MapSlicesEditFieldLabel2.Enable = 'off';
                app.MapSlicesSlider2.Enable = 'off';
                app.MapSlicesEditField3.Enable = 'off';
                app.MapSlicesEditFieldLabel3.Enable = 'off';
                app.MapSlicesSlider3.Enable = 'off';
                app.CBVScaleEditField.Enable = 'off';
                app.CBVScaleAutoButton.Enable = 'off';
                app.CBFScaleEditField.Enable = 'off';
                app.CBFScaleAutoButton.Enable = 'off';
                app.MTTScaleEditField.Enable = 'off';
                app.MTTScaleAutoButton.Enable = 'off';
                app.TTPScaleEditField.Enable = 'off';
                app.TTPScaleAutoButton.Enable = 'off';
                app.CBVlcScaleEditField.Enable = 'off';
                app.CBVlcScaleAutoButton.Enable = 'off';
                app.K2ScaleEditField.Enable = 'off';
                app.K2ScaleAutoButton.Enable = 'off';
                app.CBVScaleMaxLabel.Enable = 'off';
                app.CBFScaleMaxLabel.Enable = 'off';
                app.MTTScaleMaxLabel.Enable = 'off';
                app.TTPScaleMaxLabel.Enable = 'off';
                app.CBVlcScaleMaxLabel.Enable = 'off';
                app.K2ScaleMaxLabel.Enable = 'off';
                
                app.CBVgrayButton.Enable = 'off';
                app.CBVlipariButton.Enable = 'off';
                app.CBVwinterButton.Enable = 'off';
                app.CBVparulaButton.Enable = 'off';
                app.CBVnaviaButton.Enable = 'off';

                app.CBFgrayButton.Enable = 'off';
                app.CBFlipariButton.Enable = 'off';
                app.CBFwinterButton.Enable = 'off';
                app.CBFparulaButton.Enable = 'off';
                app.CBFnaviaButton.Enable = 'off';

                app.MTTgrayButton.Enable = 'off';
                app.MTTlipariButton.Enable = 'off';
                app.MTTwinterButton.Enable = 'off';
                app.MTTparulaButton.Enable = 'off';
                app.MTTnaviaButton.Enable = 'off';

                app.TTPgrayButton.Enable = 'off';
                app.TTPlipariButton.Enable = 'off';
                app.TTPwinterButton.Enable = 'off';
                app.TTPparulaButton.Enable = 'off';
                app.TTPnaviaButton.Enable = 'off';

                app.CBVlcgrayButton.Enable = 'off';
                app.CBVllipariButton.Enable = 'off';
                app.CBVlcwinterButton.Enable = 'off';
                app.CBVlcparulaButton.Enable = 'off';
                app.CBVlcNaviaButton.Enable = 'off';

                app.K2grayButton.Enable = 'off';
                app.K2lipariButton.Enable = 'off';
                app.K2winterButton.Enable = 'off';
                app.K2parulaButton.Enable = 'off';
                app.K2naviaButton.Enable = 'off';

            else

                app.MapSlicesEditField1.Enable = 'on';
                app.MapSlicesEditFieldLabel1.Enable = 'on';
                app.MapSlicesSlider1.Enable = 'on';
                app.MapSlicesEditField2.Enable = 'on';
                app.MapSlicesEditFieldLabel2.Enable = 'on';
                app.MapSlicesSlider2.Enable = 'on';
                app.MapSlicesEditField3.Enable = 'on';
                app.MapSlicesEditFieldLabel3.Enable = 'on';
                app.MapSlicesSlider3.Enable = 'on';
                app.CBVScaleEditField.Enable = 'on';
                app.CBVScaleAutoButton.Enable = 'on';
                app.CBFScaleEditField.Enable = 'on';
                app.CBFScaleAutoButton.Enable = 'on';
                app.MTTScaleEditField.Enable = 'on';
                app.MTTScaleAutoButton.Enable = 'on';
                app.TTPScaleEditField.Enable = 'on';
                app.TTPScaleAutoButton.Enable = 'on';
                app.CBVlcScaleEditField.Enable = 'on';
                app.CBVlcScaleAutoButton.Enable = 'on';
                app.K2ScaleEditField.Enable = 'on';
                app.K2ScaleAutoButton.Enable = 'on';
                app.CBVScaleMaxLabel.Enable = 'on';
                app.CBFScaleMaxLabel.Enable = 'on';
                app.MTTScaleMaxLabel.Enable = 'on';
                app.TTPScaleMaxLabel.Enable = 'on';
                app.CBVlcScaleMaxLabel.Enable = 'on';
                app.K2ScaleMaxLabel.Enable = 'on';
                
                app.CBVgrayButton.Enable = 'on';
                app.CBVlipariButton.Enable = 'on';
                app.CBVwinterButton.Enable = 'on';
                app.CBVparulaButton.Enable = 'on';
                app.CBVnaviaButton.Enable = 'on';

                app.CBFgrayButton.Enable = 'on';
                app.CBFlipariButton.Enable = 'on';
                app.CBFwinterButton.Enable = 'on';
                app.CBFparulaButton.Enable = 'on';
                app.CBFnaviaButton.Enable = 'on';

                app.MTTgrayButton.Enable = 'on';
                app.MTTlipariButton.Enable = 'on';
                app.MTTwinterButton.Enable = 'on';
                app.MTTparulaButton.Enable = 'on';
                app.MTTnaviaButton.Enable = 'on';

                app.TTPgrayButton.Enable = 'on';
                app.TTPlipariButton.Enable = 'on';
                app.TTPwinterButton.Enable = 'on';
                app.TTPparulaButton.Enable = 'on';
                app.TTPnaviaButton.Enable = 'on';

                app.CBVlcgrayButton.Enable = 'on';
                app.CBVllipariButton.Enable = 'on';
                app.CBVlcwinterButton.Enable = 'on';
                app.CBVlcparulaButton.Enable = 'on';
                app.CBVlcNaviaButton.Enable = 'on';

                app.K2grayButton.Enable = 'on';
                app.K2lipariButton.Enable = 'on';
                app.K2winterButton.Enable = 'on';
                app.K2parulaButton.Enable = 'on';
                app.K2naviaButton.Enable = 'on';
            
            end
            
            drawnow;
    
        end % SetMapsButtons


        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % Credits
            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';      
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            
            % Icons
            app.LoadDCMfileButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitDSCButton.Icon = 'exponential.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.CBVImage.ImageSource = 'square.gif';
            app.CBFImage.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.MTTImage.ImageSource = 'square.gif';
            app.TTPImage.ImageSource = 'square.gif';
            app.CBVlcImage.ImageSource = 'square.gif';
            app.K2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapsButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.CBVMapFig.Toolbar.Visible = 'off';
            app.MTTMapFig.Toolbar.Visible = 'off';
            app.CBVlcMapFig.Toolbar.Visible = 'off';
            app.K2MapFig.Toolbar.Visible = 'off';
            app.ArterialInputFunctionPlot.Toolbar.Visible = 'off';
            app.ConcInputFunctionPlot.Toolbar.Visible = 'off';
            app.ResidualPlot.Toolbar.Visible = 'off';
            app.ConcentrationPlot.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.CBVMapFig.Interactions = [];
            app.MTTMapFig.Interactions = [];
            app.CBVlcMapFig.Interactions = [];
            app.K2MapFig.Interactions = [];
            app.ArterialInputFunctionPlot.Interactions = [];
            app.ConcInputFunctionPlot.Interactions = [];
            app.ResidualPlot.Interactions = [];
            app.ConcentrationPlot.Interactions = [];
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)
          
            if app.d.validDataFlag

                cla(app.RawDataFig);
                hold(app.RawDataFig,'on');

                % Image to show
                im = squeeze(app.images(app.DynamicsEditField.Value,app.SlicesEditField.Value,:,:));
             
                % Scale
                if app.AutoLWButton.Value == 1
                    maxIm = max(im(:));
                    app.WindowEditField.Value = maxIm;
                    app.WindowSlider.Value = maxIm;
                    app.LevelEditField.Value = maxIm/2;
                    app.LevelSlider.Value = maxIm/2;
                end

                % Window and Level
                im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                [dx,dy] = size(im);
                xlim(app.RawDataFig,[1,dx]);
                ylim(app.RawDataFig,[1,dy]);

                imshow(rot90(im),[0,1],'InitialMagnification','fit','Parent',app.RawDataFig);

                imo = squeeze(1 - app.d.mask(app.SlicesEditField.Value,:,:));
                [xcor, ycor] = find(imo);

                originalUnits = app.RawDataFig.Units;
                app.RawDataFig.Units = 'Points';
                axSizePix = app.RawDataFig.Position(3:4);
                app.RawDataFig.Units = originalUnits;
                pixPerUnit = axSizePix ./ [app.d.range(xlim(app.RawDataFig)),app.d.range(ylim(app.RawDataFig))];

                scatter(app.RawDataFig, xcor, app.d.dimy-ycor+1, ceil(min(pixPerUnit))^2, 's', 'MarkerFaceColor','r','MarkerEdgeColor','r','MarkerEdgeAlpha',0.4,'MarkerFaceAlpha',0.4);
    
                % Image aspect ratio
                daspect(app.RawDataFig,[1 app.d.pixelAspectRatio 1]);

                hold(app.RawDataFig,'off');
                drawnow;

                % Set some limits now
                app.thresholdEditField.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
                app.thresholdEditField.Value = app.threshold(app.SlicesEditField.Value);
                app.ThresholdSlider.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
                app.ThresholdSlider.Value = app.threshold(app.SlicesEditField.Value);

            end
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.d.validFitFlag

                hold(app.CBVMapFig,'on');
                hold(app.CBFMapFig,'on');
                hold(app.MTTMapFig,'on');
                hold(app.TTPMapFig,'on');
                hold(app.CBVlcMapFig,'on');
                hold(app.K2MapFig,'on');

                % Plot the rCBV map
                im = squeeze(app.d.cbv(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                [dx,dy] = size(im);
                xlim(app.CBVMapFig,[1,dx]);
                ylim(app.CBVMapFig,[1,dy]);
                imshow(rot90(im),[0 app.CBVScaleEditField.Value],'Colormap',app.cbvcmap,'InitialMagnification','fit','Parent',app.CBVMapFig);
                daspect(app.CBVMapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.CBVMapFig,'east','Ticks',[]);

                % Plot the rCBF map
                im = squeeze(app.d.cbf(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                xlim(app.CBFMapFig,[1,dx]);
                ylim(app.CBFMapFig,[1,dy]);
                imshow(rot90(im),[0 app.CBFScaleEditField.Value],'Colormap',app.cbfcmap,'InitialMagnification','fit','Parent',app.CBFMapFig);
                daspect(app.CBFMapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.CBFMapFig,'east','Ticks',[]);

                % Plot the MTT map
                im = squeeze(app.d.mtt(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                xlim(app.MTTMapFig,[1,dx]);
                ylim(app.MTTMapFig,[1,dy]);
                imshow(rot90(im),[0 app.MTTScaleEditField.Value],'Colormap',app.mttcmap,'InitialMagnification','fit','Parent',app.MTTMapFig);
                daspect(app.MTTMapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.MTTMapFig,'east','Ticks',[]);

                % Plot the TTP map
                im = squeeze(app.d.ttp(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                xlim(app.TTPMapFig,[1,dx]);
                ylim(app.TTPMapFig,[1,dy]);
                imshow(rot90(im),[0 app.TTPScaleEditField.Value],'Colormap',app.ttpcmap,'InitialMagnification','fit','Parent',app.TTPMapFig);
                daspect(app.TTPMapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.TTPMapFig,'east','Ticks',[]);

                % Plot the rCBVlc map
                im = squeeze(app.d.cbvlc(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                xlim(app.CBVlcMapFig,[1,dx]);
                ylim(app.CBVlcMapFig,[1,dy]);
                imshow(rot90(im),[0 app.CBVlcScaleEditField.Value],'Colormap',app.cbvlccmap,'InitialMagnification','fit','Parent',app.CBVlcMapFig);
                daspect(app.CBVlcMapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.CBVlcMapFig,'east','Ticks',[]);

                % Plot the K2 map
                im = squeeze(app.d.k2map(app.MapSlicesEditField1.Value,:,:)).*squeeze(app.d.mask(app.MapSlicesEditField1.Value,:,:));
                xlim(app.K2MapFig,[1,dx]);
                ylim(app.K2MapFig,[1,dy]);
                imshow(rot90(im),[0 app.K2ScaleEditField.Value],'Colormap',app.k2cmap,'InitialMagnification','fit','Parent',app.K2MapFig);
                daspect(app.K2MapFig,[1 app.d.pixelAspectRatio 1]);
                colorbar(app.K2MapFig,'east','Ticks',[]);

                hold(app.CBVMapFig,'off');
                hold(app.CBFMapFig,'off');
                hold(app.MTTMapFig,'off');
                hold(app.TTPMapFig,'off');
                hold(app.CBVlcMapFig,'off');
                hold(app.K2MapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Plots the fitted data and difference
        function PlotFit(app)

            if app.d.validDataFlag && app.d.validFitFlag

                try

                    % Plot fit and residuals if present
                    cla(app.ResidualPlot);
                    cla(app.ConcentrationPlot);

                    xcor = app.XViewField.Value;
                    ycor = app.YViewField.Value;
                    zcor = app.SlicesEditField.Value;
                    time = app.d.timeAIF/1000;

                    hold(app.ConcentrationPlot,'on');
                    signal = squeeze(app.d.conc(1:length(time),zcor,xcor,ycor))';
                    svdres = app.d.res(1:length(time),zcor,xcor,ycor)';
                    fit = filter(app.d.gv,1,svdres).*app.d.tr/1000;
                    mx = max(max(signal(:)),max(fit(:)));
                    plot(app.ConcentrationPlot,time,signal);
                    plot(app.ConcentrationPlot,time,fit,'LineWidth',2);
                    xlim(app.ConcentrationPlot,[-0.5, 1.1*max(time(:))]);
                    ylim(app.ConcentrationPlot,[-5, 1.1*mx]);
                    hold(app.ConcentrationPlot,'off');

                    hold(app.ResidualPlot,'on');
                    res = signal - fit;
                    plot(app.ResidualPlot,time,res);
                    xlim(app.ResidualPlot,[-0.5, 1.1*max(time(:))]);
                    ylim(app.ResidualPlot,[-0.5*(2.5+mx),0.5*(2.5+mx)]);
                    hold(app.ResidualPlot,'off');

                catch ME

                    app.TextMessage(ME.message);

                end

            end

        end % PlotFit


        % Plots the AIF from a collection of points
        function PlotAIF(app)

            if app.d.validDataFlag

                try

                    % Determine points average
                    xcor = app.roiPoints(:,1);
                    ycor = app.roiPoints(:,2);
                    zcor = app.roiPoints(:,3);
                    datapoints = zeros(size(app.images,1),length(xcor));
                    for i = 1:length(xcor)
                        datapoints(:,i) = app.images(:,zcor(i),xcor(i),ycor(i));
                    end
                    app.d.dataAIF = mean(datapoints,2);

                    % Plot ellipsoid region
                    if nnz(app.d.ellipseROI(:)) > 0
                        [xcor, ycor] = find(app.d.ellipseROI);
                        hold(app.RawDataFig,'on');
                        delete(app.roi);
                        originalUnits = app.RawDataFig.Units;
                        app.RawDataFig.Units = 'Points';
                        axSizePix = app.RawDataFig.Position(3:4);
                        app.RawDataFig.Units = originalUnits;
                        pixPerUnit = axSizePix ./ [range(xlim(app.RawDataFig)),range(ylim(app.RawDataFig))];
                        app.roi = scatter(app.RawDataFig, xcor, app.d.dimy-ycor+1, ceil(min(pixPerUnit))^2, 's', 'MarkerFaceColor','g','MarkerEdgeColor','g','MarkerEdgeAlpha',0.1,'MarkerFaceAlpha',0.3);
                        delete(app.cross);
                        xcor = app.XViewField.Value;
                        ycor = app.YViewField.Value;
                        app.cross = drawcrosshair(app.RawDataFig, 'Position', [xcor app.d.dimy-ycor+1], 'Color', 'g', 'LineWidth', 1);
                        hold(app.RawDataFig,'off');
                    end

                    % Plot AIF signal in graph
                    cla(app.ArterialInputFunctionPlot);
                    hold(app.ArterialInputFunctionPlot,'on');
                    signal = app.d.dataAIF/app.d.maxImageValue;
                    time = app.d.timeAIF/1000;
                    plot(app.ArterialInputFunctionPlot,time,signal);
                    xlim(app.ArterialInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
                    ylim(app.ArterialInputFunctionPlot,[0, 1.1*max(signal(:))]);
                    hold(app.ArterialInputFunctionPlot,'off');

                    % Plot AIF concentration in graph
                    cla(app.ConcInputFunctionPlot);
                    hold(app.ConcInputFunctionPlot,'on');
                    signal = app.d.aif;
                    plot(app.ConcInputFunctionPlot,time,signal);
                    xlim(app.ConcInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
                    ylim(app.ConcInputFunctionPlot,[-1, 1.1*max(signal(:))]);
                    hold(app.ConcInputFunctionPlot,'off');
         
                catch ME

                    app.TextMessage(ME.message);

                end

            end
           
        end % PlotAIF


        % Draw a cross-hair on the images
        function PlotCross(app,xcor,ycor,zcor)

            if app.d.validDataFlag

                delete(app.cross);
                app.cross = drawcrosshair(app.RawDataFig, 'Position', [xcor app.d.dimy-ycor+1], 'Color', 'g', 'LineWidth', 1);

                % Cross data
                app.d.dataAIF = app.images(:,zcor,xcor,ycor);
             
                % Plot
                cla(app.ConcInputFunctionPlot);
                cla(app.ArterialInputFunctionPlot);
                hold(app.ArterialInputFunctionPlot,'on');

                signal = app.d.dataAIF/app.d.maxImageValue;
                time = app.d.timeAIF/1000;

                plot(app.ArterialInputFunctionPlot,time,signal);
                xlim(app.ArterialInputFunctionPlot,[-0.5, 1.1*max(time(:))]);
                ylim(app.ArterialInputFunctionPlot,[0, 1.1*max(signal(:))]);

                hold(app.ArterialInputFunctionPlot,'off');

            end

        end % PlotCross


        % Draw points on the image
        function PlotAIFpoints(app)

            if app.d.validDataFlag

                xcor = app.roiPoints(:,1);
                ycor = app.roiPoints(:,2);
                
                hold(app.RawDataFig,'on');
                delete(app.aifroi);
                app.aifroi = scatter(app.RawDataFig, xcor, app.d.dimy-ycor+1, 's', 'MarkerFaceColor',app.blue,'MarkerEdgeColor',app.blue);
                hold(app.RawDataFig,'off');
           
            end

        end % PlotPoint


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)

            % Message
            app.TextMessage('Setting scan parameters ...');
           
            % Image aspect ratio
            app.AspectRatioViewField.Value = app.d.aspectRatio;

            % Images
            app.images = app.d.data(app.FirstDynamicEditField.Value:end,:,:,:);
            app.d.timeAIF = (0:size(app.images,1)-1)'*app.d.tr;
            app.d.nr = size(app.images,1);
  
            % Show parameters & set limits
            app.TRViewField.Value = app.d.tr;
            app.TEViewField.Value = app.d.te;
            app.FlipAngleViewField.Value = app.d.fa;
            app.NRDynamicsViewField.Value = app.d.nr;
            app.dimxViewField.Value = app.d.dimx;
            app.dimyViewField.Value = app.d.dimy;
            app.SlicesViewField.Value = app.d.ns;
            app.SlicesEditField.Value = 1;
            app.SlicesEditField.Limits = [1, app.d.ns];
            app.SlicesSlider.Value = 1;
            app.SlicesSlider.Limits = [1, app.d.ns];
            app.DynamicsEditField.Value = 1;
            app.DynamicsEditField.Limits = [1, app.d.nr];
            app.DynamicsSlider.Value = 1;
            app.DynamicsSlider.Limits = [1, app.d.nr];
            app.MapSlicesEditField1.Limits = [1, app.d.ns];
            app.MapSlicesSlider1.Limits = [1, app.d.ns];
            app.MapSlicesEditField2.Limits = [1, app.d.ns];
            app.MapSlicesSlider2.Limits = [1, app.d.ns];
            app.MapSlicesEditField3.Limits = [1, app.d.ns];
            app.MapSlicesSlider3.Limits = [1, app.d.ns];
            app.SliceThicknessViewField.Value = app.d.sl;
            app.FOVViewField.Value = app.d.fov;
            
            % Starting color maps
            app.cbvcmap = naviaColorMap(); 
            app.cbfcmap = naviaColorMap(); 
            app.mttcmap = lipariColorMap(); 
            app.ttpcmap = lipariColorMap();  
            app.cbvlccmap = lipariColorMap(); 
            app.k2cmap = naviaColorMap(); 
             
            % Define empty maps
            app.d.cbv = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            app.d.cbf = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            app.d.mtt = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            app.d.ttp = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            app.d.cbvlc = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            app.d.k2map = zeros(app.d.ns,app.d.dimx,app.d.dimy);
            
            % Window and level
            app.WindowEditField.Value = app.d.maxImageValue;
            app.LevelEditField.Value = round(app.d.maxImageValue/2);
            app.WindowSlider.Value = app.d.maxImageValue;
            app.LevelSlider.Value = round(app.d.maxImageValue/2);
            app.AutoLWButton.Value = 1;
    
            % Define mask initially with ones
            app.d.mask = ones(app.d.ns,app.d.dimx,app.d.dimy);

            % Roi stuff
            app.structuringElement2D = strel("disk",1);
            app.roiPoints = [];
            
            % Reset treshold values
            app.threshold = zeros(1,app.d.ns);
          
        end % SetParameters


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------
    
    methods (Access = public)
        

        % Text messages in the app
        function TextMessage(app, message)

            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app, status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.Text = 'OK';
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.Text = 'WARNING';
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'statusbusy.gif';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.Text = 'BUSY';
                    
            end
            
            drawnow;
            
        end
               
        
    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % Disable unnecessary warnings
            clc;
            warning off;

            % Set app theme to light
            theme(app.DSCmapUIFigure,"light")
            
            % main window noname
            app.DSCmapUIFigure.Name = '';

            % main window noname
            app.DSCmapUIFigure.Name = '';
                       
            % add subfolders to search folder
            if (~isdeployed)
                app.oldFolder = pwd;
                info = dir(which('DSCmapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));
            end
            
            % Empty file import and export folders
            app.dicomImportFolder = '';
            app.dicomExportFolder = '';
            app.gifExportFolder = '';

            % Create empty objects of classes
            app.d = dsc();
            
            % Initialize user interface
            app.InitializeUI;
         
            % Set app colors
            app.ColorsButtonGroupSelectionChanged;

            % PC, MAC, Unix
            try
                if ismac
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                elseif isunix
                    app.TextMessage('Unix version');
                elseif ispc
                    app.TextMessage('PC version');
                else
                    app.TextMessage('Unknown platform');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixFolder, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixFolder)
                        result = [result ';' elastixFolder];
                        setenv('PATH', result);
                    end
                end
            catch
            end
 
            % Version check
            app.CheckVersionFnc;

            % Start the parallel pool if it is not running already
            try
                app.TextMessage("Starting the parallel pool ...");
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % OK
            app.SetStatus(0);
            app.SetButtons([1,0,0,0,0,0,1]);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ColorsButtonGroup.SelectedObject;
       
            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);
            
        end

        % Button pushed function: LoadDCMfileButton
        function LoadDicomFileButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapsButtons(0);
            app.SetStatus(3);
            
            % Initialize flags
            app.d.validDataFlag = false;
            app.d.validFitFlag = false;
           
            try

                % Ask for dicom import folder
                f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                app.dicomImportFolder = uigetdir(app.dicomImportFolder,'DCM import folder ...');
                delete(f);
                figure(app.DSCmapUIFigure);

                % Check for user cancel
                if app.dicomImportFolder == 0
                    app.dicomImportFolder = '';
                    ME = MException('DSCmapp:userCancel','ERROR: Please select a valid data folder ...');
                    throw(ME);
                end

                % Read the dicom files
                app.DicomFolderViewField.Value = app.dicomImportFolder;
                app.d = app.d.readData(app);
  
                if ~app.d.validDataFlag
                    ME = MException('DSCmapp:userCancel','ERROR: Please select a valid data folder ...');
                    throw(ME);
                end

                % Success
                app.SetParameters;

                % Set buttons
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);

                % Show the image
                app.SlicesEditField.Value = round(app.d.ns/2);
                app.SlicesEditFieldValueChanged;

                app.PlotMaps;

            catch ME

                % Failure
                app.d.validDataFlag = false;
                app.TextMessage(ME.message);
                app.dicomImportFolder = '';

                % Set status
                app.SetLights([0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,1]);
                app.SetStatus(2);

            end
            
        end

        % Value changed function: DynamicsEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)

            if app.d.validDataFlag

                % Adjust the slider
                app.SlicesSlider.Value = app.SlicesEditField.Value;
                app.DynamicsSlider.Value = app.DynamicsEditField.Value;

                % Plot the raw image
                app.PlotImageFig;

            end

        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)
            
            if app.d.validDataFlag

                % Adjust the slices edit field value
                app.SlicesEditField.Value = round(app.SlicesSlider.Value);

                % Continue with the changed slices edit field callback
                SlicesEditFieldValueChanged(app, event)

            end

        end

        % Value changed function: DynamicsSlider
        function DynamicsSliderValueChanged(app, event)
            
            if app.d.validDataFlag

                % Adjust the echoes edit field value
                app.DynamicsEditField.Value = round(app.DynamicsSlider.Value);

                % Continue with the changed slices / echoes edit field callback
                SlicesEditFieldValueChanged(app, event)

            end
            
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.d.validDataFlag
                
                % Indicate busy
                app.SetLights([2,2,0,2,2,2]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for slice = 1:app.d.ns
                        app.threshold(slice) = graythresh(mat2gray(app.images(1,slice,:,:))) * max(squeeze(app.images(1,slice,:)));
                    end

                    % Set the automatic mask for all slices
                    app.d = app.d.maskImages(app);
                    
                    % Display the threshold value for the current slice
                    app.thresholdEditField.Value = app.threshold(app.SlicesEditField.Value);

                    % Display the mask in the image
                    app.PlotImageFig;

                    % Apply the new mask to the maps if present
                    app.PlotMaps;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Indicate busy
                app.SetLights([2,2,1,2,2,2]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: thresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice
            cslice = app.SlicesEditField.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice) = app.thresholdEditField.Value;
            app.ThresholdSlider.Value = app.thresholdEditField.Value;

            % Apply the new threshold
            app.d = app.d.maskImages(app);
           
            % Display the result;
            app.PlotImageFig
            app.PlotMaps;

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.thresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion
            try

                for slice = 1:app.d.ns
                    app.d.mask(slice,:,:) = 1-imerode(1-squeeze(app.d.mask(slice,:,:)),app.structuringElement2D);
                end

                % Display the result;
                app.PlotImageFig;
                app.PlotMaps;

            catch ME
                
                app.TextMessage(ME.message);
                
            end
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation
            
            try

                for slice = 1:app.d.ns
                    app.d.mask(slice,:,:) = 1-imdilate(1-squeeze(app.d.mask(slice,:,:)),app.structuringElement2D);
                end

                % Display the result;
                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try

                % Morphological dilation 

                for slice = 1:app.d.ns
                    app.d.mask(slice,:,:) = 1-imdilate(1-squeeze(app.d.mask(slice,:,:)),app.structuringElement2D);
                    app.d.mask(slice,:,:) = 1-imerode(1-squeeze(app.d.mask(slice,:,:)),app.structuringElement2D);
                end

                % Display the result;
                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            try

                % Morphological dilation
                for slice = 1:app.d.ns
                    app.d.mask(slice,:,:) = 1-imopen(1-squeeze(app.d.mask(slice,:,:)),app.structuringElement2D);
                end

                % Display the result;
                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             
            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)
            
            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            if app.d.validDataFlag

                % Indicate busy
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapsButtons(0);
                app.SetStatus(3);
                app.d.validFitFlag = false;
                app.validRegFlag = false;
                app.AbortRegButton.Enable = 'on';
                app.abortRegFlag = false;
                
                try

                    % Register all slices to unweighted image
                    registerImages(app);

                    % reset maps
                    app.d.cbv = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.cbf = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.mtt = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.ttp = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.cbvlc = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.k2map = zeros(app.d.ns,app.d.dimx,app.d.dimy);
                    app.d.mask = ones(app.d.ns,app.d.dimx,app.d.dimy);

                    % replot the images
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(0);
             
                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');
                    app.SetLights([1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(2);
             
                end

            end

        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
              app.abortRegFlag = true;  

        end

        % Image clicked function: RawImage
        function RawImageClicked(app, event)

            if app.d.validDataFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.DSCmapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [310, 408]; % position of bottom-left corner of image in the app (295, 412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Correct for non-square image
                aratio = app.d.aspectRatio;
                xsize = app.d.dimx;
                ysize = app.d.dimy;

                % Correct for non-square image
                imsize = 320;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.SlicesEditField.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.XViewField.Value = xcor;
                app.YViewField.Value = ycor;

                % Plot cross
                app.PlotCross(xcor,ycor,zcor);

                % Determine auto AIF
                try
                    delete(app.aifroi);
                    delete(app.roi);
                    app.d = app.d.AIFauto(app);
                    app.PlotAIF;
                    app.PlotAIFpoints;
                catch ME %#ok<NASGU>
                    % app.TextMessage(ME.message);
                    app.TextMessage('AIF search failed, try different region ...');
                end

                % Plot fit
                app.PlotFit;

            end

        end

        % Button pushed function: FitDSCButton
        function FitDSCButtonPushed(app, event)
            
            if app.d.validDataFlag

                app.SetLights([2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapsButtons(0);
                app.SetStatus(3);
                app.TextMessage('Fitting DSC maps ....');

                app.d.validFitFlag = false;
             
                try
    
                    % Timing parameters
                    app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                    app.FitProgressGauge.Value = 0;
                 
                    % Do the fit
                    app.d = app.d.DSCfit(app);
                   
                    % Plot the AIF points in the image
                    app.PlotImageFig;
                    app.PlotAIF;
                    app.PlotAIFpoints;
                    app.PlotFit;

                    % Plot the maps & update the scales
                    app.MapSlicesEditField1.Value = round(app.d.ns/2);
                    app.MapSlicesEditField1ValueChanged;
                    app.CBVScaleAutoButtonPushed;
                    app.CBVColorMapButtonGroupSelectionChanged;
                    app.CBFScaleAutoButtonPushed;
                    app.CBFColorMapButtonGroupSelectionChanged;
                    app.MTTScaleAutoButtonPushed;
                    app.MTTColorMapButtonGroupSelectionChanged;
                    app.TTPScaleAutoButtonPushed;
                    app.TTPColorMapButtonGroupSelectionChanged;
                    app.CBVlcScaleAutoButtonPushed;
                    app.CBVlcColorMapButtonGroupSelectionChanged;
                    app.K2ScaleAutoButtonPushed;
                    app.K2ColorMapButtonGroupSelectionChanged;

                    % Ready
                    app.TextMessage('Finished ... ');
                    app.EstimatedFitTimeViewField.Value = 'Finished ...';
                    app.FitProgressGauge.Value = 100;

                    % Reset buttons
                    app.SetLights([2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapsButtons(1);
                    app.SetStatus(0);
               
               catch ME
                
                    app.TextMessage(ME.message);

                    % Reset buttons
                    app.SetLights([2,2,2,0,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapsButtons(0);
                    app.FitProgressGauge.Value = 0;
                    app.SetStatus(2);
                    app.d.validFitFlag = false;

                end

            end

        end

        % Value changed function: FirstDynamicEditField
        function FirstDynamicEditFieldValueChanged(app, event)
            
            app.SetParameters;
            app.PlotImageFig;
            
        end

        % Value changed function: MapSlicesEditField1
        function MapSlicesEditField1ValueChanged(app, event)

            if app.d.validFitFlag

                % Change the slider value according to the edit number
                app.MapSlicesSlider1.Value = round(app.MapSlicesEditField1.Value);
                app.MapSlicesEditField2.Value = app.MapSlicesEditField1.Value;
                app.MapSlicesSlider2.Value = app.MapSlicesSlider1.Value;
                app.MapSlicesEditField3.Value = app.MapSlicesEditField1.Value;
                app.MapSlicesSlider3.Value = app.MapSlicesSlider1.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end

        end

        % Value changed function: MapSlicesEditField2
        function MapSlicesEditField2ValueChanged(app, event)
            
            if app.d.validFitFlag

                % Change the slider value according to the edit number
                app.MapSlicesSlider2.Value = round(app.MapSlicesEditField2.Value);
                app.MapSlicesEditField1.Value = app.MapSlicesEditField2.Value;
                app.MapSlicesSlider1.Value = app.MapSlicesSlider2.Value;
                app.MapSlicesEditField3.Value = app.MapSlicesEditField2.Value;
                app.MapSlicesSlider3.Value = app.MapSlicesSlider2.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end
            
        end

        % Value changed function: MapSlicesEditField3
        function MapSlicesEditField3ValueChanged(app, event)

            if app.d.validFitFlag

                % Change the slider value according to the edit number
                app.MapSlicesSlider3.Value = round(app.MapSlicesEditField3.Value);
                app.MapSlicesEditField1.Value = app.MapSlicesEditField3.Value;
                app.MapSlicesSlider1.Value = app.MapSlicesSlider3.Value;
                app.MapSlicesEditField2.Value = app.MapSlicesEditField3.Value;
                app.MapSlicesSlider2.Value = app.MapSlicesSlider3.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end

        end

        % Value changed function: MapSlicesSlider1
        function MapSlicesSlider1ValueChanged(app, event)

            if app.d.validFitFlag

                % Change the Slice nr value according to the slider
                app.MapSlicesEditField1.Value = round(app.MapSlicesSlider1.Value);
                app.MapSlicesEditField2.Value = round(app.MapSlicesSlider1.Value);
                app.MapSlicesSlider2.Value = app.MapSlicesSlider1.Value;
                app.MapSlicesEditField3.Value = round(app.MapSlicesSlider1.Value);
                app.MapSlicesSlider3.Value = app.MapSlicesSlider1.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end

        end

        % Value changed function: MapSlicesSlider2
        function MapSlicesSlider2ValueChanged(app, event)

            if app.d.validFitFlag

                % Change the Slice nr value according to the slider
                app.MapSlicesEditField2.Value = round(app.MapSlicesSlider2.Value);
                app.MapSlicesEditField1.Value = round(app.MapSlicesSlider2.Value);
                app.MapSlicesSlider1.Value = app.MapSlicesSlider2.Value;
                app.MapSlicesEditField3.Value = round(app.MapSlicesSlider2.Value);
                app.MapSlicesSlider3.Value = app.MapSlicesSlider2.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end

        end

        % Value changed function: MapSlicesSlider3
        function MapSlicesSlider3ValueChanged(app, event)

            if app.d.validFitFlag

                % Change the Slice nr value according to the slider
                app.MapSlicesEditField3.Value = round(app.MapSlicesSlider3.Value);
                app.MapSlicesEditField1.Value = round(app.MapSlicesSlider3.Value);
                app.MapSlicesSlider1.Value = app.MapSlicesSlider3.Value;
                app.MapSlicesEditField2.Value = round(app.MapSlicesSlider3.Value);
                app.MapSlicesSlider2.Value = app.MapSlicesSlider3.Value;

                % Plot the maps for the new slice
                app.PlotMaps;

            end

        end

        % Value changed function: CBFScaleEditField, CBVScaleEditField, 
        % ...and 4 other components
        function MapScaleEditFieldValueChanged(app, event)
            
            % Plot the maps with the new scale
            app.PlotMaps;
            
        end

        % Button pushed function: CBVScaleAutoButton
        function CBVScaleAutoButtonPushed(app, event)

            if app.d.validFitFlag

                % Auto-scale = 3 x median of the CBV values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.cbv(app.MapSlicesEditField1.Value,:)))),2);
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.CBVScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Button pushed function: CBFScaleAutoButton
        function CBFScaleAutoButtonPushed(app, event)
                        
            if app.d.validFitFlag

                % Auto-scale = 2 x median of the CBF values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.cbf(app.MapSlicesEditField1.Value,:)))),2);
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.CBFScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Button pushed function: MTTScaleAutoButton
        function MTTScaleAutoButtonPushed(app, event)
                                    
            if app.d.validFitFlag

                % Auto-scale = 3 x median of the MTT values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.mtt(app.MapSlicesEditField1.Value,:)))));
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.MTTScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Button pushed function: TTPScaleAutoButton
        function TTPScaleAutoButtonPushed(app, event)
                                    
            if app.d.validFitFlag

                % Auto-scale = 3 x median of the TTP values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.ttp(app.MapSlicesEditField1.Value,:)))));
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.TTPScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Button pushed function: CBVlcScaleAutoButton
        function CBVlcScaleAutoButtonPushed(app, event)

            if app.d.validFitFlag

                % Auto-scale = 3 x median of the CBV values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.cbvlc(app.MapSlicesEditField1.Value,:)))),2);
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.CBVlcScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Button pushed function: K2ScaleAutoButton
        function K2ScaleAutoButtonPushed(app, event)

            if app.d.validFitFlag

                % Auto-scale = 3 x median of the CBV values in the image
                scale = round(3*median(nonzeros(squeeze(app.d.k2map(app.MapSlicesEditField1.Value,:)))),2);
                scale(isnan(scale)) = 1;
                scale(isinf(scale)) = 1;
                scale(scale<0.01) = 0.01;
                app.K2ScaleEditField.Value = scale;

                % Plot the map
                app.PlotMaps;

            end

        end

        % Selection changed function: CBVColorMapButtonGroup
        function CBVColorMapButtonGroupSelectionChanged(app, event)
            
            if app.CBVgrayButton.Value == 1 
                app.cbvcmap = gray; 
            end
            
            if app.CBVlipariButton.Value == 1
                app.cbvcmap = lipariColorMap(); 
            end
            
            if app.CBVwinterButton.Value == 1
                app.cbvcmap = winter; 
            end
            
            if app.CBVparulaButton.Value == 1
                app.cbvcmap = parula; 
            end
            
            if app.CBVnaviaButton.Value == 1
                app.cbvcmap = naviaColorMap(); 
            end
            
            % Background should always be black
            app.cbvcmap(1,:) = [0 0 0];
            
            % Plot the map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: CBFColorMapButtonGroup
        function CBFColorMapButtonGroupSelectionChanged(app, event)
            
            if app.CBFgrayButton.Value == 1 
                app.cbfcmap = gray; 
            end
            
            if app.CBFlipariButton.Value == 1
                app.cbfcmap = lipariColorMap();  
            end
            
            if app.CBFwinterButton.Value == 1
                app.cbfcmap = winter; 
            end
            
            if app.CBFparulaButton.Value == 1
                app.cbfcmap = parula; 
            end
            
            if app.CBFnaviaButton.Value == 1
                app.cbfcmap = naviaColorMap();  
            end
            
            % Background should always be black
            app.cbfcmap(1,:) = [0 0 0];
            
            % Plot the map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: MTTColorMapButtonGroup
        function MTTColorMapButtonGroupSelectionChanged(app, event)
           
            if app.MTTgrayButton.Value == 1 
                app.mttcmap = gray; 
            end
            
            if app.MTTlipariButton.Value == 1
                app.mttcmap = lipariColorMap();  
            end
            
            if app.MTTwinterButton.Value == 1
                app.mttcmap = winter; 
            end
            
            if app.MTTparulaButton.Value == 1
                app.mttcmap = parula; 
            end
            
            if app.MTTnaviaButton.Value == 1
                app.mttcmap = naviaColorMap();  
            end
            
            % Background should always be black
            app.mttcmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: TTPColorMapButtonGroup
        function TTPColorMapButtonGroupSelectionChanged(app, event)
                   
            if app.TTPgrayButton.Value == 1 
                app.ttpcmap = gray; 
            end
            
            if app.TTPlipariButton.Value == 1
                app.ttpcmap = lipariColorMap();  
            end
            
            if app.TTPwinterButton.Value == 1
                app.ttpcmap = winter; 
            end
            
            if app.TTPparulaButton.Value == 1
                app.ttpcmap = parula; 
            end
            
            if app.TTPnaviaButton.Value == 1
                app.ttpcmap = naviaColorMap();  
            end
            
            % Background should always be black
            app.ttpcmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: CBVlcColorMapButtonGroup
        function CBVlcColorMapButtonGroupSelectionChanged(app, event)
                               
            if app.CBVlcgrayButton.Value == 1 
                app.cbvlccmap = gray; 
            end
            
            if app.CBVllipariButton.Value == 1
                app.cbvlccmap = lipariColorMap();  
            end
            
            if app.CBVlcwinterButton.Value == 1
                app.cbvlccmap = winter; 
            end
            
            if app.CBVlcparulaButton.Value == 1
                app.cbvlccmap = parula; 
            end
            
            if app.CBVlcNaviaButton.Value == 1
                app.cbvlccmap = naviaColorMap();  
            end
            
            % Background should always be black
            app.cbvlccmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            app.PlotMaps;
                        
        end

        % Selection changed function: K2ColorMapButtonGroup
        function K2ColorMapButtonGroupSelectionChanged(app, event)

            if app.K2grayButton.Value == 1
                app.k2cmap = gray;
            end

            if app.K2lipariButton.Value == 1
                app.k2cmap = lipariColorMap(); 
            end

            if app.K2winterButton.Value == 1
                app.k2cmap = winter;
            end

            if app.K2parulaButton.Value == 1
                app.k2cmap = parula;
            end

            if app.K2naviaButton.Value == 1
                app.k2cmap = naviaColorMap(); 
            end

            % Background should always be black
            app.k2cmap(1,:) = [0 0 0];

            % Plot the M0 map with the new colormap
            app.PlotMaps;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            if app.d.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,0,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);

                try

                    app.TextMessage('DICOM export ...');

                    % Base / Data / 343 / DICOM / 8455 /
                    dcmSearchFolder = app.dicomImportFolder;
 
                    try
                        k = strfind(dcmSearchFolder,"Data");
                        fs = strfind(dcmSearchFolder(k:end),filesep);
                        baseFolder = dcmSearchFolder(1:k+fs(2)-1);
                        dcmSearchFolder = dcmSearchFolder(1:k+fs(3)-1);
                        dcmExist = true;
                    catch
                        dcmSearchFolder = app.dicomImportFolder;
                        baseFolder = app.dicomImportFolder;
                        dcmExist = false;
                    end
     
                    app.dicomExportFolder = dcmSearchFolder;
                
                    % Export to DICOM
                    if dcmExist

                        try
                            % Check if folder is actually writable
                            mkdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                            rmdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                            app.TextMessage(strcat("DICOM export folder = ",app.dicomExportFolder));
                            outputFolder = app.d.exportDCM(app.dicomExportFolder);
                        catch
                            tempFig = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                            app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DCM export folder ...');
                            delete(tempFig);
                            figure(app.DSCmapUIFigure);
                            drawnow;
                            if ~ischar(app.dicomExportFolder)
                                ME = MException('DSCmapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                            dcmExportFolder = strcat(app.dicomExportFolder,filesep,"DICOM",filesep);
                            app.TextMessage(strcat("DICOM export folder = ",dcmExportFolder));
                            outputFolder = app.d.exportDCM(dcmExportFolder);
                        end

                    else

                        % Ask for dicom export folder
                        tempFig = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                        app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DCM export folder ...');
                        delete(tempFig);
                        figure(app.DSCmapUIFigure);
                        drawnow;
                        if ~ischar(app.dicomExportFolder)
                            ME = MException('DSCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        dcmExportFolder = strcat(app.dicomExportFolder,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export folder = ",dcmExportFolder));
                        outputFolder = app.d.exportDCM(dcmExportFolder);

                    end

                    % Try additional nifti export
                    try
                        app.d.dscExportNifti(app, baseFolder, outputFolder);
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfti export failed ...");
                    end

                    % Success
                    app.TextMessage('DICOM export ready ...');
                    app.SetLights([2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.dicomExportFolder = '';
                    app.SetStatus(2);
                    app.SetLights([2,2,2,2,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    
                end

            end
        
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
         
            if app.d.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);

                % Export to animated gif
                try

                    app.TextMessage('GIF export ...');

                    try

                        app.gifExportFolder = app.dicomImportFolder;
                        k = strfind(app.gifExportFolder,"Data");
                        fs = strfind(app.gifExportFolder(k:end),filesep);
                        app.gifExportFolder = app.gifExportFolder(1:k+fs(2)-1);

                        % Check if folder is actually writable
                        mkdir(strcat(app.gifExportFolder,filesep,"testfolder"));
                        rmdir(strcat(app.gifExportFolder,filesep,"testfolder"));
                        gifFolderExist = true;

                    catch

                        gifFolderExist = false;

                    end

                    if ~gifFolderExist

                        tempFig = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                        app.gifExportFolder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        delete(tempFig);
                        figure(app.DSCmapUIFigure);
                        drawnow;
                        if ~ischar(app.gifExportFolder)
                            ME = MException('DSCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end

                    end

                    gfExportFolder = strcat(app.gifExportFolder,filesep,"GIF",filesep);
                    app.TextMessage(strcat("GIF export folder = ",gfExportFolder," ..."));

                    app.d.exportGifDSC(app, gfExportFolder);
                    
                
                    % Success
                    app.TextMessage('GIF export ready ...');
                    app.SetLights([2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.gifExportFolder = '';
                    app.SetStatus(2);
                    app.SetLights([2,2,2,2,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                   
                end

            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search folder
            if (~isdeployed)
                info = dir(which('DSCmapp.mlapp'));
                app.appFolder = info.folder;
                rmpath(genpath(app.appFolder));
                cd(app.oldFolder);
            end
            
            % Exit
            app.delete;
        
        end

        % Window scroll wheel function: DSCmapUIFigure
        function DSCmapUIFigureWindowScrollWheel(app, event)

            try
                
                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels'); 
                guiposition = get(app.DSCmapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos > 828

                    if app.d.validFitFlag

                        if verticalScrollCount > 0
                            newslice = app.MapSlicesEditField1.Value + 1;
                            newslice(newslice > app.d.ns) = 1;
                        else
                            newslice = app.MapSlicesEditField1.Value - 1;
                            newslice(newslice < 1) = app.d.ns;
                        end

                        app.MapSlicesEditField1.Value = newslice;
                        app.MapSlicesEditField1ValueChanged;
                        app.PlotMaps;

                    end

                end

            catch ME

                app.TextMessage(ME.message);

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DSCmapUIFigure = uifigure('Visible', 'off');
            app.DSCmapUIFigure.Name = 'DSCmapp';
            app.DSCmapUIFigure.Icon = fullfile(pathToMLAPP, 'DSCicon.png');
            app.DSCmapUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @DSCmapUIFigureWindowScrollWheel, true);
            app.DSCmappingLabel = uilabel(app.DSCmapUIFigure);
            app.DSCmappingLabel.Text = 'DSC mapping';
            app.Credits = uilabel(app.DSCmapUIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.DSCmapUIFigure);
            app.MessageBox = uitextarea(app.DSCmapUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Application messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.DSCmapUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'Application status'};
            app.StatusImage = uiimage(app.DSCmapUIFigure);
            app.StatusLabel = uilabel(app.DSCmapUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.MRSImage = uiimage(app.DSCmapUIFigure);
            app.TabGroup1 = uitabgroup(app.DSCmapUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadDCMfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadDCMfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomFileButtonPushed, true);
            app.LoadDCMfileButton.Enable = 'off';
            app.LoadDCMfileButton.Tooltip = {'Load DICOM diffusion-weighted data'};
            app.LoadDCMfileButton.Text = 'Load DCM';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask the images to exclude fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.FitDSCButton = uibutton(app.DashboardTab, 'push');
            app.FitDSCButton.ButtonPushedFcn = createCallbackFcn(app, @FitDSCButtonPushed, true);
            app.FitDSCButton.Enable = 'off';
            app.FitDSCButton.Tooltip = {'Start the DSC parameter fitting'};
            app.FitDSCButton.Text = 'Fit DSC';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register images using Elastix or Matlab'};
            app.RegisterButton.Text = 'Register';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation method'};
            app.RegistrationDropDown.Value = 'Translation';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.DSCmapUIFigure);
            app.rCBVrCBFTab = uitab(app.TabGroup3);
            app.rCBVrCBFTab.Title = 'rCBV / rCBF /';
            app.CBVMapFig = uiaxes(app.rCBVrCBFTab);
            app.CBVMapFig.XTick = [];
            app.CBVMapFig.YTick = [];
            app.CBFMapFig = uiaxes(app.rCBVrCBFTab);
            app.CBFMapFig.XTick = [];
            app.CBFMapFig.YTick = [];
            app.CBFImage = uiimage(app.rCBVrCBFTab);
            app.rCBVLabel = uilabel(app.rCBVrCBFTab);
            app.rCBVLabel.Tooltip = {'relative Cerebral Blood Volume'};
            app.rCBVLabel.Text = 'rCBV [-]';
            app.rCBF1sLabel = uilabel(app.rCBVrCBFTab);
            app.rCBF1sLabel.Tooltip = {'relative Cerebral Blood Flow'};
            app.rCBF1sLabel.Text = 'rCBF [1/s]';
            app.CBVImage = uiimage(app.rCBVrCBFTab);
            app.MapImageSelectionPanel1 = uipanel(app.rCBVrCBFTab);
            app.MapImageSelectionPanel1.Title = 'Image selection';
            app.MapSlicesSlider1 = uislider(app.MapImageSelectionPanel1);
            app.MapSlicesSlider1.Limits = [1 200];
            app.MapSlicesSlider1.MajorTicks = [];
            app.MapSlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider1ValueChanged, true);
            app.MapSlicesSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider1.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider1.Value = 1;
            app.MapSlicesEditFieldLabel1 = uilabel(app.MapImageSelectionPanel1);
            app.MapSlicesEditFieldLabel1.Text = 'slice';
            app.MapSlicesEditField1 = uieditfield(app.MapImageSelectionPanel1, 'numeric');
            app.MapSlicesEditField1.Limits = [1 Inf];
            app.MapSlicesEditField1.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField1ValueChanged, true);
            app.MapSlicesEditField1.Tooltip = {'Slice number'};
            app.MapSlicesEditField1.Value = 1;
            app.CBVColorMapButtonGroup = uibuttongroup(app.rCBVrCBFTab);
            app.CBVColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBVColorMapButtonGroupSelectionChanged, true);
            app.CBVColorMapButtonGroup.Title = 'Color map';
            app.CBVgrayButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVgrayButton.Text = 'gray';
            app.CBVgrayButton.Interpreter = 'html';
            app.CBVlipariButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVlipariButton.Text = 'lipari';
            app.CBVlipariButton.Interpreter = 'html';
            app.CBVwinterButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVwinterButton.Text = 'winter';
            app.CBVwinterButton.Interpreter = 'html';
            app.CBVparulaButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVparulaButton.Text = 'parula';
            app.CBVparulaButton.Interpreter = 'html';
            app.CBVnaviaButton = uiradiobutton(app.CBVColorMapButtonGroup);
            app.CBVnaviaButton.Text = 'navia';
            app.CBVnaviaButton.Interpreter = 'html';
            app.CBVnaviaButton.Value = true;
            app.CBVScaleEditField = uieditfield(app.CBVColorMapButtonGroup, 'numeric');
            app.CBVScaleEditField.Limits = [0.01 Inf];
            app.CBVScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBVScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBVScaleEditField.Tooltip = {'rCBV map scale'};
            app.CBVScaleEditField.Value = 1;
            app.CBVScaleMaxLabel = uilabel(app.CBVColorMapButtonGroup);
            app.CBVScaleMaxLabel.Text = 'max';
            app.CBVScaleAutoButton = uibutton(app.CBVColorMapButtonGroup, 'push');
            app.CBVScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBVScaleAutoButtonPushed, true);
            app.CBVScaleAutoButton.Tooltip = {'Automatic rCBV map scale setting'};
            app.CBVScaleAutoButton.Text = 'Auto';
            app.CBFColorMapButtonGroup = uibuttongroup(app.rCBVrCBFTab);
            app.CBFColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBFColorMapButtonGroupSelectionChanged, true);
            app.CBFColorMapButtonGroup.Title = 'Color map';
            app.CBFgrayButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFgrayButton.Text = 'gray';
            app.CBFgrayButton.Interpreter = 'html';
            app.CBFlipariButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFlipariButton.Text = 'lipari';
            app.CBFlipariButton.Interpreter = 'html';
            app.CBFwinterButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFwinterButton.Text = 'winter';
            app.CBFwinterButton.Interpreter = 'html';
            app.CBFparulaButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFparulaButton.Text = 'parula';
            app.CBFparulaButton.Interpreter = 'html';
            app.CBFnaviaButton = uiradiobutton(app.CBFColorMapButtonGroup);
            app.CBFnaviaButton.Text = 'navia';
            app.CBFnaviaButton.Interpreter = 'html';
            app.CBFnaviaButton.Value = true;
            app.CBFScaleEditField = uieditfield(app.CBFColorMapButtonGroup, 'numeric');
            app.CBFScaleEditField.Limits = [0.01 Inf];
            app.CBFScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBFScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBFScaleEditField.Tooltip = {'rCBF map scale'};
            app.CBFScaleEditField.Value = 1;
            app.CBFScaleMaxLabel = uilabel(app.CBFColorMapButtonGroup);
            app.CBFScaleMaxLabel.Text = 'max';
            app.CBFScaleAutoButton = uibutton(app.CBFColorMapButtonGroup, 'push');
            app.CBFScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBFScaleAutoButtonPushed, true);
            app.CBFScaleAutoButton.Tooltip = {'Automatic rCBF map scale setting'};
            app.CBFScaleAutoButton.Text = 'Auto';
            app.MTTTTPTab = uitab(app.TabGroup3);
            app.MTTTTPTab.Title = 'MTT / TTP /';
            app.MTTMapFig = uiaxes(app.MTTTTPTab);
            app.MTTMapFig.XTick = [];
            app.MTTMapFig.YTick = [];
            app.TTPMapFig = uiaxes(app.MTTTTPTab);
            app.TTPMapFig.XTick = [];
            app.TTPMapFig.YTick = [];
            app.MTTsLabel = uilabel(app.MTTTTPTab);
            app.MTTsLabel.Tooltip = {'Mean Transit Time (s)'};
            app.MTTsLabel.Text = 'MTT (s)';
            app.TTPsLabel = uilabel(app.MTTTTPTab);
            app.TTPsLabel.Tooltip = {'Time To Peak (s) relative to AIF'};
            app.TTPsLabel.Text = 'TTP (s)';
            app.MapImageSelectionPanel2 = uipanel(app.MTTTTPTab);
            app.MapImageSelectionPanel2.Title = 'Image selection';
            app.MapSlicesSlider2 = uislider(app.MapImageSelectionPanel2);
            app.MapSlicesSlider2.Limits = [1 200];
            app.MapSlicesSlider2.MajorTicks = [];
            app.MapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider2ValueChanged, true);
            app.MapSlicesSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider2.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider2.Value = 1;
            app.MapSlicesEditFieldLabel2 = uilabel(app.MapImageSelectionPanel2);
            app.MapSlicesEditFieldLabel2.Text = 'slice';
            app.MapSlicesEditField2 = uieditfield(app.MapImageSelectionPanel2, 'numeric');
            app.MapSlicesEditField2.Limits = [1 Inf];
            app.MapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField2ValueChanged, true);
            app.MapSlicesEditField2.Tooltip = {'Slice number'};
            app.MapSlicesEditField2.Value = 1;
            app.MTTImage = uiimage(app.MTTTTPTab);
            app.TTPImage = uiimage(app.MTTTTPTab);
            app.MTTColorMapButtonGroup = uibuttongroup(app.MTTTTPTab);
            app.MTTColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MTTColorMapButtonGroupSelectionChanged, true);
            app.MTTColorMapButtonGroup.Title = 'Color map';
            app.MTTgrayButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTgrayButton.Text = 'gray';
            app.MTTgrayButton.Interpreter = 'html';
            app.MTTlipariButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTlipariButton.Text = 'lipari';
            app.MTTlipariButton.Interpreter = 'html';
            app.MTTlipariButton.Value = true;
            app.MTTwinterButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTwinterButton.Text = 'winter';
            app.MTTwinterButton.Interpreter = 'html';
            app.MTTparulaButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTparulaButton.Text = 'parula';
            app.MTTparulaButton.Interpreter = 'html';
            app.MTTnaviaButton = uiradiobutton(app.MTTColorMapButtonGroup);
            app.MTTnaviaButton.Text = 'navia';
            app.MTTnaviaButton.Interpreter = 'html';
            app.MTTScaleEditField = uieditfield(app.MTTColorMapButtonGroup, 'numeric');
            app.MTTScaleEditField.Limits = [0.01 Inf];
            app.MTTScaleEditField.ValueDisplayFormat = '%.2f';
            app.MTTScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.MTTScaleEditField.Tooltip = {'MTT map scale'};
            app.MTTScaleEditField.Value = 1;
            app.MTTScaleMaxLabel = uilabel(app.MTTColorMapButtonGroup);
            app.MTTScaleMaxLabel.Text = 'max';
            app.MTTScaleAutoButton = uibutton(app.MTTColorMapButtonGroup, 'push');
            app.MTTScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @MTTScaleAutoButtonPushed, true);
            app.MTTScaleAutoButton.Tooltip = {'Automatic MTT map scale setting'};
            app.MTTScaleAutoButton.Text = 'Auto';
            app.TTPColorMapButtonGroup = uibuttongroup(app.MTTTTPTab);
            app.TTPColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TTPColorMapButtonGroupSelectionChanged, true);
            app.TTPColorMapButtonGroup.Title = 'Color map';
            app.TTPgrayButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPgrayButton.Text = 'gray';
            app.TTPgrayButton.Interpreter = 'html';
            app.TTPlipariButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPlipariButton.Text = 'lipari';
            app.TTPlipariButton.Interpreter = 'html';
            app.TTPlipariButton.Value = true;
            app.TTPwinterButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPwinterButton.Text = 'winter';
            app.TTPwinterButton.Interpreter = 'html';
            app.TTPparulaButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPparulaButton.Text = 'parula';
            app.TTPparulaButton.Interpreter = 'html';
            app.TTPnaviaButton = uiradiobutton(app.TTPColorMapButtonGroup);
            app.TTPnaviaButton.Text = 'navia';
            app.TTPnaviaButton.Interpreter = 'html';
            app.TTPScaleEditField = uieditfield(app.TTPColorMapButtonGroup, 'numeric');
            app.TTPScaleEditField.Limits = [0.01 Inf];
            app.TTPScaleEditField.ValueDisplayFormat = '%.2f';
            app.TTPScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.TTPScaleEditField.Tooltip = {'TTP map scale'};
            app.TTPScaleEditField.Value = 1;
            app.TTPScaleMaxLabel = uilabel(app.TTPColorMapButtonGroup);
            app.TTPScaleMaxLabel.Text = 'max';
            app.TTPScaleAutoButton = uibutton(app.TTPColorMapButtonGroup, 'push');
            app.TTPScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @TTPScaleAutoButtonPushed, true);
            app.TTPScaleAutoButton.Tooltip = {'Automatic TTP map scale setting'};
            app.TTPScaleAutoButton.Text = 'Auto';
            app.rCBVlcK2Tab = uitab(app.TabGroup3);
            app.rCBVlcK2Tab.Title = 'rCBVlc / K2';
            app.CBVlcMapFig = uiaxes(app.rCBVlcK2Tab);
            app.CBVlcMapFig.XTick = [];
            app.CBVlcMapFig.YTick = [];
            app.K2MapFig = uiaxes(app.rCBVlcK2Tab);
            app.K2MapFig.XTick = [];
            app.K2MapFig.YTick = [];
            app.K2Image = uiimage(app.rCBVlcK2Tab);
            app.rCBVlcLabel = uilabel(app.rCBVlcK2Tab);
            app.rCBVlcLabel.Tooltip = {'relative Cerebral Vascular Volume, with leakage correction'};
            app.rCBVlcLabel.Text = 'rCBVlc [-]';
            app.K2Label = uilabel(app.rCBVlcK2Tab);
            app.K2Label.Tooltip = {'leakage rate constant (1/s)'};
            app.K2Label.Text = 'K2 (1/s)';
            app.CBVlcImage = uiimage(app.rCBVlcK2Tab);
            app.MapImageSelectionPanel3 = uipanel(app.rCBVlcK2Tab);
            app.MapImageSelectionPanel3.Title = 'Image selection';
            app.MapSlicesSlider3 = uislider(app.MapImageSelectionPanel3);
            app.MapSlicesSlider3.Limits = [1 200];
            app.MapSlicesSlider3.MajorTicks = [];
            app.MapSlicesSlider3.ValueChangedFcn = createCallbackFcn(app, @MapSlicesSlider3ValueChanged, true);
            app.MapSlicesSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.MapSlicesSlider3.Tooltip = {'Slice number slider'};
            app.MapSlicesSlider3.Value = 1;
            app.MapSlicesEditFieldLabel3 = uilabel(app.MapImageSelectionPanel3);
            app.MapSlicesEditFieldLabel3.Text = 'slice';
            app.MapSlicesEditField3 = uieditfield(app.MapImageSelectionPanel3, 'numeric');
            app.MapSlicesEditField3.Limits = [1 Inf];
            app.MapSlicesEditField3.ValueDisplayFormat = '%.0f';
            app.MapSlicesEditField3.ValueChangedFcn = createCallbackFcn(app, @MapSlicesEditField3ValueChanged, true);
            app.MapSlicesEditField3.Tooltip = {'Slice number'};
            app.MapSlicesEditField3.Value = 1;
            app.CBVlcColorMapButtonGroup = uibuttongroup(app.rCBVlcK2Tab);
            app.CBVlcColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBVlcColorMapButtonGroupSelectionChanged, true);
            app.CBVlcColorMapButtonGroup.Title = 'Color map';
            app.CBVlcgrayButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcgrayButton.Text = 'gray';
            app.CBVlcgrayButton.Interpreter = 'html';
            app.CBVllipariButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVllipariButton.Text = 'lipari';
            app.CBVllipariButton.Interpreter = 'html';
            app.CBVllipariButton.Value = true;
            app.CBVlcwinterButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcwinterButton.Text = 'winter';
            app.CBVlcwinterButton.Interpreter = 'html';
            app.CBVlcparulaButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcparulaButton.Text = 'parula';
            app.CBVlcparulaButton.Interpreter = 'html';
            app.CBVlcNaviaButton = uiradiobutton(app.CBVlcColorMapButtonGroup);
            app.CBVlcNaviaButton.Text = 'navia';
            app.CBVlcNaviaButton.Interpreter = 'html';
            app.CBVlcScaleEditField = uieditfield(app.CBVlcColorMapButtonGroup, 'numeric');
            app.CBVlcScaleEditField.Limits = [0.01 Inf];
            app.CBVlcScaleEditField.ValueDisplayFormat = '%.2f';
            app.CBVlcScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.CBVlcScaleEditField.Tooltip = {'rCBV map scale'};
            app.CBVlcScaleEditField.Value = 1;
            app.CBVlcScaleMaxLabel = uilabel(app.CBVlcColorMapButtonGroup);
            app.CBVlcScaleMaxLabel.Text = 'max';
            app.CBVlcScaleAutoButton = uibutton(app.CBVlcColorMapButtonGroup, 'push');
            app.CBVlcScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @CBVlcScaleAutoButtonPushed, true);
            app.CBVlcScaleAutoButton.Tooltip = {'Automatic rCBV map scale setting'};
            app.CBVlcScaleAutoButton.Text = 'Auto';
            app.K2ColorMapButtonGroup = uibuttongroup(app.rCBVlcK2Tab);
            app.K2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @K2ColorMapButtonGroupSelectionChanged, true);
            app.K2ColorMapButtonGroup.Title = 'Color map';
            app.K2grayButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2grayButton.Text = 'gray';
            app.K2grayButton.Interpreter = 'html';
            app.K2lipariButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2lipariButton.Text = 'lipari';
            app.K2lipariButton.Interpreter = 'html';
            app.K2winterButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2winterButton.Text = 'winter';
            app.K2winterButton.Interpreter = 'html';
            app.K2parulaButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2parulaButton.Text = 'parula';
            app.K2parulaButton.Interpreter = 'html';
            app.K2naviaButton = uiradiobutton(app.K2ColorMapButtonGroup);
            app.K2naviaButton.Text = 'navia';
            app.K2naviaButton.Interpreter = 'html';
            app.K2naviaButton.Value = true;
            app.K2ScaleEditField = uieditfield(app.K2ColorMapButtonGroup, 'numeric');
            app.K2ScaleEditField.Limits = [0.01 Inf];
            app.K2ScaleEditField.ValueDisplayFormat = '%.2f';
            app.K2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MapScaleEditFieldValueChanged, true);
            app.K2ScaleEditField.Tooltip = {'rCBF map scale'};
            app.K2ScaleEditField.Value = 1;
            app.K2ScaleMaxLabel = uilabel(app.K2ColorMapButtonGroup);
            app.K2ScaleMaxLabel.Text = 'max';
            app.K2ScaleAutoButton = uibutton(app.K2ColorMapButtonGroup, 'push');
            app.K2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @K2ScaleAutoButtonPushed, true);
            app.K2ScaleAutoButton.Tooltip = {'Automatic rCBF map scale setting'};
            app.K2ScaleAutoButton.Text = 'Auto';
            app.TabGroup2 = uitabgroup(app.DSCmapUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSelectionPanel = uipanel(app.RawImagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice number'};
            app.SlicesEditField.Value = 1;
            app.SlicesSlider = uislider(app.ImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider.Tooltip = {'Slice number slider'};
            app.SlicesSlider.Value = 1;
            app.DynamicsEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.DynamicsEditField.Tooltip = {'Image number'};
            app.DynamicsEditField.Value = 1;
            app.DynamicsSlider = uislider(app.ImageSelectionPanel);
            app.DynamicsSlider.Limits = [1 200];
            app.DynamicsSlider.MajorTicks = [];
            app.DynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @DynamicsSliderValueChanged, true);
            app.DynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider.Tooltip = {'Image number slider'};
            app.DynamicsSlider.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.SlicesEditFieldLabel.Text = 'slice';
            app.DynamicsEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.DynamicsEditFieldLabel.Text = 'dynamic';
            app.MaskSettingsPanel = uipanel(app.RawImagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 1001 2001 3001 4001 5001 6001 7001 8001 9001 10001 11001 12001 13001 14001 15001 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.thresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.thresholdEditField.Tooltip = {'Mask threshold'};
            app.ImageWindowLevelPanel = uipanel(app.RawImagesTab);
            app.ImageWindowLevelPanel.Title = 'Image window/level';
            app.WindowEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.WindowSlider = uislider(app.ImageWindowLevelPanel);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.LevelSlider = uislider(app.ImageWindowLevelPanel);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.LevelEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.LevelEditFieldLabel.Text = 'L';
            app.WindowEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.WindowEditFieldLabel.Text = 'W';
            app.AutoLWButton = uibutton(app.ImageWindowLevelPanel, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.RawImage = uiimage(app.RawImagesTab);
            app.RawImage.ImageClickedFcn = createCallbackFcn(app, @RawImageClicked, true);
            app.SequenceParametersTab = uitab(app.TabGroup2);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DicomFolderViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DicomFolderViewFieldLabel.Text = 'DCM folder';
            app.DicomFolderViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.DicomFolderViewField.Editable = 'off';
            app.DicomFolderViewField.Tooltip = {'DICOM data folder'};
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.FlipAngleViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FlipAngleViewFieldLabel.Text = 'flip angle';
            app.FlipAngleViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FlipAngleViewField.Limits = [0 Inf];
            app.FlipAngleViewField.Editable = 'off';
            app.FlipAngleViewField.Tooltip = {'Flip angle'};
            app.NRDynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NRDynamicsViewFieldLabel.Text = '#dynamics';
            app.NRDynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NRDynamicsViewField.Limits = [0 Inf];
            app.NRDynamicsViewField.Editable = 'off';
            app.NRDynamicsViewField.Tooltip = {'Number of images'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.3f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.dimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimxViewFieldLabel.Text = 'dim X';
            app.dimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.dimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimyViewFieldLabel.Text = 'dim Y';
            app.dimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '#slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.SliceThicknessViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.SoundsLabel = uilabel(app.AppSettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.AppSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.AppSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsLabel = uilabel(app.AppSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.AppSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.TabGroup4 = uitabgroup(app.DSCmapUIFigure);
            app.ArterialInputFunctionTab = uitab(app.TabGroup4);
            app.ArterialInputFunctionTab.Title = 'Arterial input function';
            app.ArterialInputFunctionPlot = uiaxes(app.ArterialInputFunctionTab);
            xlabel(app.ArterialInputFunctionPlot, 'time (s)')
            ylabel(app.ArterialInputFunctionPlot, 'signal')
            app.ArterialInputFunctionPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ConcInputFunctionPlot = uiaxes(app.ArterialInputFunctionTab);
            xlabel(app.ConcInputFunctionPlot, 'time (s)')
            ylabel(app.ConcInputFunctionPlot, 'AIF')
            app.XViewFieldLabel = uilabel(app.ArterialInputFunctionTab);
            app.XViewFieldLabel.Text = 'X';
            app.XViewField = uieditfield(app.ArterialInputFunctionTab, 'numeric');
            app.XViewField.ValueDisplayFormat = '%.0f';
            app.XViewField.Editable = 'off';
            app.XViewField.Tooltip = {'X-coordinate'};
            app.YViewFieldLabel = uilabel(app.ArterialInputFunctionTab);
            app.YViewFieldLabel.Text = 'Y';
            app.YViewField = uieditfield(app.ArterialInputFunctionTab, 'numeric');
            app.YViewField.ValueDisplayFormat = '%.0f';
            app.YViewField.Editable = 'off';
            app.YViewField.Tooltip = {'Y-coordinate'};
            app.FitParametersTab = uitab(app.TabGroup4);
            app.FitParametersTab.Title = 'Fit parameters';
            app.DataSelectionPanel = uipanel(app.FitParametersTab);
            app.DataSelectionPanel.Title = 'Data selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DataSelectionPanel);
            app.FirstDynamicEditFieldLabel.Enable = 'off';
            app.FirstDynamicEditFieldLabel.Text = 'First dynamic';
            app.FirstDynamicEditField = uieditfield(app.DataSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 40];
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.FirstDynamicEditField.Enable = 'off';
            app.FirstDynamicEditField.Tooltip = {'First dynamic (used to discard transient)'};
            app.FirstDynamicEditField.Value = 15;
            app.AIFSettingsPanel = uipanel(app.FitParametersTab);
            app.AIFSettingsPanel.Title = 'AIF settings';
            app.LongAxisEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.LongAxisEditFieldLabel.Enable = 'off';
            app.LongAxisEditFieldLabel.Text = 'ROI y axis (%)';
            app.LongAxisEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.LongAxisEditField.Limits = [5 100];
            app.LongAxisEditField.ValueDisplayFormat = '%.0f';
            app.LongAxisEditField.Enable = 'off';
            app.LongAxisEditField.Tooltip = {'ROI vertical axis (%)'};
            app.LongAxisEditField.Value = 15;
            app.ShortAxisEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.ShortAxisEditFieldLabel.Enable = 'off';
            app.ShortAxisEditFieldLabel.Text = 'ROI x axis (%)';
            app.ShortAxisEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.ShortAxisEditField.Limits = [5 100];
            app.ShortAxisEditField.ValueDisplayFormat = '%.0f';
            app.ShortAxisEditField.Enable = 'off';
            app.ShortAxisEditField.Tooltip = {'ROI horizontal axis (%)'};
            app.ShortAxisEditField.Value = 15;
            app.MaxAIFpixelsEditFieldLabel = uilabel(app.AIFSettingsPanel);
            app.MaxAIFpixelsEditFieldLabel.Enable = 'off';
            app.MaxAIFpixelsEditFieldLabel.Text = 'Max # AIF pixels';
            app.MaxAIFpixelsEditField = uieditfield(app.AIFSettingsPanel, 'numeric');
            app.MaxAIFpixelsEditField.Limits = [5 20];
            app.MaxAIFpixelsEditField.ValueDisplayFormat = '%.0f';
            app.MaxAIFpixelsEditField.Enable = 'off';
            app.MaxAIFpixelsEditField.Tooltip = {'Maximum allowed AIF pixels in search area'};
            app.MaxAIFpixelsEditField.Value = 10;
            app.QuadraticCheckBox = uicheckbox(app.AIFSettingsPanel);
            app.QuadraticCheckBox.Tooltip = {'Linear or quadratic (checked) R2* with contrast agent concentration '};
            app.QuadraticCheckBox.Enable = 'off';
            app.QuadraticCheckBox.Text = '';
            app.QuadraticCheckBoxLabel = uilabel(app.AIFSettingsPanel);
            app.QuadraticCheckBoxLabel.Enable = 'off';
            app.QuadraticCheckBoxLabel.Tooltip = {'Linear or quadratic (checked) R2* with contrast agent concentration '};
            app.QuadraticCheckBoxLabel.Text = 'Quadratic R2* [CA]';
            app.CBFfitMethodButtonGroup = uibuttongroup(app.FitParametersTab);
            app.CBFfitMethodButtonGroup.Tooltip = {''};
            app.CBFfitMethodButtonGroup.Title = 'DSC fit parameters';
            app.SVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.SVDButton.Enable = 'off';
            app.SVDButton.Tooltip = {'Deconvolution method'};
            app.SVDButton.Text = '';
            app.SVDButton.Interpreter = 'html';
            app.SVDButton.Value = true;
            app.cSVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.cSVDButton.Enable = 'off';
            app.cSVDButton.Tooltip = {'Deconvolution method'};
            app.cSVDButton.Text = '';
            app.cSVDButton.Interpreter = 'html';
            app.oSVDButton = uiradiobutton(app.CBFfitMethodButtonGroup);
            app.oSVDButton.Enable = 'off';
            app.oSVDButton.Tooltip = {'Deconvolution method'};
            app.oSVDButton.Text = '';
            app.oSVDButton.Interpreter = 'html';
            app.KhEditFieldLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.KhEditFieldLabel.Enable = 'off';
            app.KhEditFieldLabel.Text = 'Kh';
            app.KhEditField = uieditfield(app.CBFfitMethodButtonGroup, 'numeric');
            app.KhEditField.Limits = [0.1 2];
            app.KhEditField.ValueDisplayFormat = '%.2f';
            app.KhEditField.Enable = 'off';
            app.KhEditField.Tooltip = {'Kh = hematocrit factor in the capillary system (typically 0.67). When set to 1, relative estimates of CBV are obtained.'};
            app.KhEditField.Value = 1;
            app.RhoEditFieldLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.RhoEditFieldLabel.Enable = 'off';
            app.RhoEditFieldLabel.Text = 'ρ';
            app.RhoEditField = uieditfield(app.CBFfitMethodButtonGroup, 'numeric');
            app.RhoEditField.Limits = [0.1 2];
            app.RhoEditField.ValueDisplayFormat = '%.2f';
            app.RhoEditField.Enable = 'off';
            app.RhoEditField.Tooltip = {'Rho represents the density of blood (typically 1 g/ml tissue), by default it is set to one, in this case, relative estimates of the CBV parameter are obtained.'};
            app.RhoEditField.Value = 1;
            app.SVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.SVDLabel.Enable = 'off';
            app.SVDLabel.Tooltip = {'Convolution method'};
            app.SVDLabel.Text = 'SVD';
            app.cSVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.cSVDLabel.Enable = 'off';
            app.cSVDLabel.Tooltip = {'Convolution method'};
            app.cSVDLabel.Text = 'cSVD';
            app.oSVDLabel = uilabel(app.CBFfitMethodButtonGroup);
            app.oSVDLabel.Enable = 'off';
            app.oSVDLabel.Tooltip = {'Convolution method'};
            app.oSVDLabel.Text = 'oSVD';
            app.FitEvaluationTab = uitab(app.TabGroup4);
            app.FitEvaluationTab.Title = 'Fit evaluation';
            app.ResidualPlot = uiaxes(app.FitEvaluationTab);
            xlabel(app.ResidualPlot, 'time (s)')
            ylabel(app.ResidualPlot, 'residual')
            app.ConcentrationPlot = uiaxes(app.FitEvaluationTab);
            xlabel(app.ConcentrationPlot, 'time (s)')
            ylabel(app.ConcentrationPlot, 'conc')
            app.Hyperlink = uihyperlink(app.DSCmapUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.DSCmapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSCmapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DSCmapUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DSCmapUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSCmapUIFigure)
        end
    end
end